classdef carrier_dialog < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        GridLayout2                    matlab.ui.container.GridLayout
        SaveSettingsButton             matlab.ui.control.Button
        CancelButton                   matlab.ui.control.Button
        GridLayout3                    matlab.ui.container.GridLayout
        ParametersPanel                matlab.ui.container.Panel
        GridLayout4                    matlab.ui.container.GridLayout
        CarrierModulationFrequencySpinnerLabel  matlab.ui.control.Label
        CarrierModulationFrequencySpinner  matlab.ui.control.Spinner
        CheckBoxLowpass                matlab.ui.control.CheckBox
        LowpassFilterLabel             matlab.ui.control.Label
        LowpassFilterspanSpinnerLabel  matlab.ui.control.Label
        LowpassFilterspanSpinner       matlab.ui.control.Spinner
        DemodulationFrequencySpinnerLabel  matlab.ui.control.Label
        DemodulationFrequencySpinner   matlab.ui.control.Spinner
        ModulationLabel                matlab.ui.control.Label
        DemodulationLabel              matlab.ui.control.Label
        ReceiverPhaseSpinnerLabel      matlab.ui.control.Label
        ReceiverPhaseSpinner           matlab.ui.control.Spinner
        LowpassCutofffrequencySpinnerLabel  matlab.ui.control.Label
        LowpassCutofffrequencySpinner  matlab.ui.control.Spinner
    end

    
    properties (Access = private)
        main_app % Description
        
    end
    
    methods (Access = private)
                
        function disable_filter(app)
            app.LowpassCutofffrequencySpinner.Editable = false;
            app.LowpassCutofffrequencySpinner.Enable = false;
            app.LowpassCutofffrequencySpinnerLabel.Enable = false;
            app.LowpassFilterspanSpinner.Editable = false;
            app.LowpassFilterspanSpinner.Enable = false;
            app.LowpassFilterspanSpinnerLabel.Enable = false;
        end
        function enable_filter(app)
            app.LowpassCutofffrequencySpinner.Editable = true;
            app.LowpassCutofffrequencySpinner.Enable = true;
            app.LowpassCutofffrequencySpinnerLabel.Enable = true;
            app.LowpassFilterspanSpinner.Editable = true;
            app.LowpassFilterspanSpinner.Enable = true;
            app.LowpassFilterspanSpinnerLabel.Enable = true;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, calling_app)
            app.main_app = calling_app;
            
            app.CarrierModulationFrequencySpinner.Value = calling_app.param.carrier_modulation.frequency;
            app.DemodulationFrequencySpinner.Value = calling_app.param.carrier_demodulation.frequency;
            
            app.ReceiverPhaseSpinner.Value = calling_app.param.carrier_demodulation.phase/pi;
            app.CheckBoxLowpass.Value = calling_app.param.carrier_demodulation.lowpass;
            
            if calling_app.param.carrier_demodulation.lowpass
                app.LowpassCutofffrequencySpinner.Value = ...
                    calling_app.param.carrier_demodulation.cutoff_frequency;
                app.LowpassFilterspanSpinner.Value = ...
                    calling_app.param.carrier_demodulation.filterspan;
            else
                app.disable_filter();
            end
            
%             if strcmp(calling_app.param.carrier_demodulation.lowpass_limit,'on')
%                 app.enable_filter();
%                 app.enable_span();
%                 app.CheckBoxLowpass.Value = true;
%                 app.FilterTypeDropDown.Value = calling_app.param.carrier_demodulation.lowpass_type;
%                 app.LowpassFilterspanSpinner.Value = calling_app.param.carrier_demodulation.lowpass_filterspan;
%             else
%                 app.CheckBoxLowpass.Value = false;
%                 app.disable_filter();
%                 app.disable_span();
%             end

        end

        % Button pushed function: SaveSettingsButton
        function SaveSettingsButtonPushed(app, event)
%             switch app.CarrierModulationTypeButtonGroup.SelectedObject.Text
%                 case 'AM'
%                     carrier_modulation.type = 'am';
%                     carrier_demodulation.type = 'am';
%                     carrier_modulation.frequency = app.CarrierModulationFrequencySpinner.Value;
%                     carrier_demodulation.frequency = app.DemodulationFrequencySpinner.Value;
%                     if app.CheckBoxLowpass.Value
%                         carrier_demodulation.lowpass_limit = 'on';
%                         carrier_demodulation.lowpass_type = app.FilterTypeDropDown.Value;
%                         carrier_demodulation.lowpass_filterspan = app.LowpassFilterspanSpinner.Value;
%                     else
%                         carrier_demodulation.lowpass_limit = 'off';
%                     end
%             end
            carrier_modulation.frequency = app.CarrierModulationFrequencySpinner.Value;
            carrier_demodulation.frequency = app.DemodulationFrequencySpinner.Value;
            carrier_demodulation.phase = app.ReceiverPhaseSpinner.Value*pi;
            carrier_demodulation.lowpass = app.CheckBoxLowpass.Value;
            
            if carrier_demodulation.lowpass
                carrier_demodulation.cutoff_frequency = ...
                    app.LowpassCutofffrequencySpinner.Value;
                carrier_demodulation.filterspan = ...
                    app.LowpassFilterspanSpinner.Value;
            end
            
            carrier_modulation.type = 'am';
            carrier_demodulation.type = 'am';
            
            
            app.main_app.param.carrier_modulation = carrier_modulation;
            app.main_app.param.carrier_demodulation = carrier_demodulation;
            app.main_app.update_shown_parameters();
            delete(app);
        end

        % Value changed function: CheckBoxLowpass
        function CheckBoxLowpassValueChanged(app, event)
            value = app.CheckBoxLowpass.Value;
            
            if value
                app.enable_filter();
                app.LowpassCutofffrequencySpinner.Value = ...
                    app.CarrierModulationFrequencySpinner.Value;
            else
                app.disable_filter();
            end
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            delete(app);
        end

        % Value changed function: LowpassFilterspanSpinner
        function LowpassFilterspanSpinnerValueChanged(app, event)
            value = app.LowpassFilterspanSpinner.Value;
            if mod(value,2)
                uialert(app.UIFigure,'Filter Span must be an even number larger than zero (2,4,6,...)','Invalid Number');
                app.LowpassFilterspanSpinner.Value = event.PreviousValue;
            end
        end

        % Value changed function: CarrierModulationFrequencySpinner
        function CarrierModulationFrequencySpinnerValueChanged(app, event)
            value = app.CarrierModulationFrequencySpinner.Value;
            app.DemodulationFrequencySpinner.Value = value;
            app.LowpassCutofffrequencySpinner.Value = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.SaveSettingsButton = uibutton(app.GridLayout2, 'push');
            app.SaveSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveSettingsButtonPushed, true);
            app.SaveSettingsButton.Text = 'Save Settings';
            app.CancelButton = uibutton(app.GridLayout2, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.ParametersPanel = uipanel(app.GridLayout3);
            app.ParametersPanel.Title = 'Parameters';
            app.GridLayout4 = uigridlayout(app.ParametersPanel);
            app.CarrierModulationFrequencySpinnerLabel = uilabel(app.GridLayout4);
            app.CarrierModulationFrequencySpinnerLabel.Text = 'Carrier ModulationFrequency';
            app.CarrierModulationFrequencySpinner = uispinner(app.GridLayout4);
            app.CarrierModulationFrequencySpinner.Limits = [2 Inf];
            app.CarrierModulationFrequencySpinner.ValueDisplayFormat = '%11.4g [1/Ts]';
            app.CarrierModulationFrequencySpinner.ValueChangedFcn = createCallbackFcn(app, @CarrierModulationFrequencySpinnerValueChanged, true);
            app.CarrierModulationFrequencySpinner.Value = 2;
            app.CheckBoxLowpass = uicheckbox(app.GridLayout4);
            app.CheckBoxLowpass.ValueChangedFcn = createCallbackFcn(app, @CheckBoxLowpassValueChanged, true);
            app.CheckBoxLowpass.Text = '';
            app.LowpassFilterLabel = uilabel(app.GridLayout4);
            app.LowpassFilterLabel.Text = 'Lowpass Filter';
            app.LowpassFilterspanSpinnerLabel = uilabel(app.GridLayout4);
            app.LowpassFilterspanSpinnerLabel.Text = 'Lowpass Filterspan';
            app.LowpassFilterspanSpinner = uispinner(app.GridLayout4);
            app.LowpassFilterspanSpinner.Step = 2;
            app.LowpassFilterspanSpinner.Limits = [2 Inf];
            app.LowpassFilterspanSpinner.RoundFractionalValues = 'on';
            app.LowpassFilterspanSpinner.ValueChangedFcn = createCallbackFcn(app, @LowpassFilterspanSpinnerValueChanged, true);
            app.LowpassFilterspanSpinner.Value = 256;
            app.DemodulationFrequencySpinnerLabel = uilabel(app.GridLayout4);
            app.DemodulationFrequencySpinnerLabel.Text = 'Demodulation Frequency';
            app.DemodulationFrequencySpinner = uispinner(app.GridLayout4);
            app.DemodulationFrequencySpinner.Step = 0.1;
            app.DemodulationFrequencySpinner.Limits = [2 Inf];
            app.DemodulationFrequencySpinner.ValueDisplayFormat = '%11.4g [1/Ts]';
            app.DemodulationFrequencySpinner.Value = 2;
            app.ModulationLabel = uilabel(app.GridLayout4);
            app.ModulationLabel.Text = 'Modulation';
            app.DemodulationLabel = uilabel(app.GridLayout4);
            app.DemodulationLabel.Text = 'Demodulation';
            app.ReceiverPhaseSpinnerLabel = uilabel(app.GridLayout4);
            app.ReceiverPhaseSpinnerLabel.Text = 'Receiver Phase';
            app.ReceiverPhaseSpinner = uispinner(app.GridLayout4);
            app.ReceiverPhaseSpinner.Step = 0.1;
            app.ReceiverPhaseSpinner.Limits = [0 2];
            app.ReceiverPhaseSpinner.ValueDisplayFormat = '%11.4g pi';
            app.LowpassCutofffrequencySpinnerLabel = uilabel(app.GridLayout4);
            app.LowpassCutofffrequencySpinnerLabel.Text = 'Lowpass Cutoff frequency';
            app.LowpassCutofffrequencySpinner = uispinner(app.GridLayout4);
            app.LowpassCutofffrequencySpinner.Step = 0.1;
            app.LowpassCutofffrequencySpinner.Limits = [0 Inf];
            app.LowpassCutofffrequencySpinner.ValueDisplayFormat = '%11.4g [1/Ts]';
            app.LowpassCutofffrequencySpinner.Value = 2;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = carrier_dialog(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end