classdef channel_dialog < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        GridLayout2                    matlab.ui.container.GridLayout
        CancelButton                   matlab.ui.control.Button
        SaveSettingsButton             matlab.ui.control.Button
        GridLayout3                    matlab.ui.container.GridLayout
        NoisePanel                     matlab.ui.container.Panel
        GridLayout4                    matlab.ui.container.GridLayout
        NoiseTypeDropDownLabel         matlab.ui.control.Label
        NoiseTypeDropDown              matlab.ui.control.DropDown
        SNRTypeDropDownLabel           matlab.ui.control.Label
        SNRTypeDropDown                matlab.ui.control.DropDown
        SNRValueSpinnerLabel           matlab.ui.control.Label
        Snr                            matlab.ui.control.Spinner
        BandwidthLimitationPanel       matlab.ui.container.Panel
        GridLayout5                    matlab.ui.container.GridLayout
        CheckBoxBandwidth              matlab.ui.control.CheckBox
        BandwidthLimitationLabel       matlab.ui.control.Label
        ChannelBandwidthSpinnerLabel   matlab.ui.control.Label
        ChannelBandwidthSpinner        matlab.ui.control.Spinner
        ChannelFilterspanSpinnerLabel  matlab.ui.control.Label
        ChannelFilterspanSpinner       matlab.ui.control.Spinner
    end

    
    properties (Access = private)
        main_app % Description
    end
    
    methods (Access = private)
        
        function disable_snr(app)
            app.SNRTypeDropDown.Editable = false;
            app.SNRTypeDropDown.Enable = false;
            app.Snr.Editable = false;
            app.Snr.Enable = false;
        end
        function enable_snr(app)
            app.SNRTypeDropDown.Editable = true;
            app.SNRTypeDropDown.Enable = true;
            app.Snr.Editable = true;
            app.Snr.Enable = true;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, calling_app)
            app.main_app = calling_app;
            
            if strcmp(calling_app.param.pulse_shaping.type, 'none')
                app.ChannelBandwidthSpinner.Enable = false;
                app.ChannelBandwidthSpinner.Editable = false;
                app.ChannelBandwidthSpinnerLabel.Enable = false;
                app.ChannelFilterspanSpinner.Enable = false;
                app.ChannelFilterspanSpinner.Editable = false;
                app.ChannelFilterspanSpinnerLabel.Enable = false;
                app.BandwidthLimitationLabel.Enable = false;
                app.CheckBoxBandwidth.Enable = false;
            end
            
            if calling_app.param.channel.is_bandwidth_limited
                app.CheckBoxBandwidth.Value = true;
                app.ChannelBandwidthSpinner.Value = ...
                    calling_app.param.channel.bandwidth;
                app.ChannelFilterspanSpinner.Value = ...
                    calling_app.param.channel.filterspan;
            else
                app.CheckBoxBandwidth.Value = false;
                app.ChannelBandwidthSpinner.Enable = false;
                app.ChannelBandwidthSpinner.Editable = false;
                app.ChannelBandwidthSpinnerLabel.Enable = false;
                app.ChannelFilterspanSpinner.Enable = false;
                app.ChannelFilterspanSpinner.Editable = false;
                app.ChannelFilterspanSpinnerLabel.Enable = false;
            end
            
            switch calling_app.param.channel.type
                case 'none'
                    app.NoiseTypeDropDown.Value = 'none';
                    app.disable_snr();            
                case 'awgn'
                    app.NoiseTypeDropDown.Value = 'awgn';
                    app.enable_snr();
                    app.SNRTypeDropDown.Value = calling_app.param.channel.snr_type;
                    app.Snr.Value = calling_app.param.channel.snr;
            end
        end

        % Value changed function: NoiseTypeDropDown
        function NoiseTypeDropDownValueChanged(app, event)
            value = app.NoiseTypeDropDown.Value;
            if strcmp(value,'none')
                app.disable_snr();
            else
                app.enable_snr();
            end
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            delete(app);
        end

        % Button pushed function: SaveSettingsButton
        function SaveSettingsButtonPushed(app, event)
            channel.type = app.NoiseTypeDropDown.Value;
            if ~strcmp(channel.type,'none')
                channel.snr_type = app.SNRTypeDropDown.Value;
                channel.snr = app.Snr.Value;
            end
            
            if app.CheckBoxBandwidth.Value
                channel.is_bandwidth_limited = true;
                channel.bandwidth = app.ChannelBandwidthSpinner.Value;
                channel.filterspan = app.ChannelFilterspanSpinner.Value;
            else 
                channel.is_bandwidth_limited = false;
            end
            
            channel.is_initialized = false;
            
            app.main_app.param.channel = channel;
            
            if app.main_app.is_run
                app.main_app.UpToDateLabel.Visible = 'on';
            end
            app.main_app.update_shown_parameters();
            delete(app);
        end

        % Value changed function: CheckBoxBandwidth
        function CheckBoxBandwidthValueChanged(app, event)
            value = app.CheckBoxBandwidth.Value;
            if value
                app.ChannelBandwidthSpinner.Enable = true;
                app.ChannelBandwidthSpinner.Editable = true;
                app.ChannelBandwidthSpinnerLabel.Enable = true;
                app.ChannelFilterspanSpinner.Enable = true;
                app.ChannelFilterspanSpinner.Editable = true;
                app.ChannelFilterspanSpinnerLabel.Enable = true;
            else
                app.ChannelBandwidthSpinner.Enable = false;
                app.ChannelBandwidthSpinner.Editable = false;
                app.ChannelBandwidthSpinnerLabel.Enable = false;
                app.ChannelFilterspanSpinner.Enable = false;
                app.ChannelFilterspanSpinner.Editable = false;
                app.ChannelFilterspanSpinnerLabel.Enable = false;
            end
        end

        % Value changed function: ChannelFilterspanSpinner
        function ChannelFilterspanSpinnerValueChanged(app, event)
            value = app.ChannelFilterspanSpinner.Value;
            if mod(value,2)
                uialert(app.UIFigure,'Filter Span must be an even number larger than zero (2,4,6,...)','Invalid Number');
                app.ChannelFilterspanSpinner = event.PreviousValue;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.CancelButton = uibutton(app.GridLayout2, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.SaveSettingsButton = uibutton(app.GridLayout2, 'push');
            app.SaveSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveSettingsButtonPushed, true);
            app.SaveSettingsButton.Text = 'Save Settings';
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.NoisePanel = uipanel(app.GridLayout3);
            app.NoisePanel.Title = 'Noise';
            app.GridLayout4 = uigridlayout(app.NoisePanel);
            app.NoiseTypeDropDownLabel = uilabel(app.GridLayout4);
            app.NoiseTypeDropDownLabel.Text = 'Noise Type';
            app.NoiseTypeDropDown = uidropdown(app.GridLayout4);
            app.NoiseTypeDropDown.Items = {'None', 'AWGN'};
            app.NoiseTypeDropDown.ItemsData = {'none', 'awgn'};
            app.NoiseTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @NoiseTypeDropDownValueChanged, true);
            app.NoiseTypeDropDown.Value = 'awgn';
            app.SNRTypeDropDownLabel = uilabel(app.GridLayout4);
            app.SNRTypeDropDownLabel.Text = 'SNR Type';
            app.SNRTypeDropDown = uidropdown(app.GridLayout4);
            app.SNRTypeDropDown.Items = {'Eb/N0', 'Es/N0', 'SNR'};
            app.SNRTypeDropDown.ItemsData = {'ebn0', 'esn0', 'snr'};
            app.SNRTypeDropDown.Value = 'ebn0';
            app.SNRValueSpinnerLabel = uilabel(app.GridLayout4);
            app.SNRValueSpinnerLabel.Text = 'SNR Value';
            app.Snr = uispinner(app.GridLayout4);
            app.Snr.ValueDisplayFormat = '%11.4g [dB]';
            app.Snr.Value = 20;
            app.BandwidthLimitationPanel = uipanel(app.GridLayout3);
            app.BandwidthLimitationPanel.Title = 'Bandwidth Limitation';
            app.GridLayout5 = uigridlayout(app.BandwidthLimitationPanel);
            app.CheckBoxBandwidth = uicheckbox(app.GridLayout5);
            app.CheckBoxBandwidth.ValueChangedFcn = createCallbackFcn(app, @CheckBoxBandwidthValueChanged, true);
            app.CheckBoxBandwidth.Text = '';
            app.BandwidthLimitationLabel = uilabel(app.GridLayout5);
            app.BandwidthLimitationLabel.Text = 'Bandwidth Limitation:';
            app.ChannelBandwidthSpinnerLabel = uilabel(app.GridLayout5);
            app.ChannelBandwidthSpinnerLabel.Text = 'Channel Bandwidth';
            app.ChannelBandwidthSpinner = uispinner(app.GridLayout5);
            app.ChannelBandwidthSpinner.Step = 0.1;
            app.ChannelBandwidthSpinner.Limits = [0 Inf];
            app.ChannelBandwidthSpinner.ValueDisplayFormat = '%11.4g [1/Ts]';
            app.ChannelBandwidthSpinner.Value = 4;
            app.ChannelFilterspanSpinnerLabel = uilabel(app.GridLayout5);
            app.ChannelFilterspanSpinnerLabel.Text = 'Channel Filterspan';
            app.ChannelFilterspanSpinner = uispinner(app.GridLayout5);
            app.ChannelFilterspanSpinner.Step = 2;
            app.ChannelFilterspanSpinner.Limits = [2 Inf];
            app.ChannelFilterspanSpinner.RoundFractionalValues = 'on';
            app.ChannelFilterspanSpinner.ValueDisplayFormat = '%11.4g ';
            app.ChannelFilterspanSpinner.ValueChangedFcn = createCallbackFcn(app, @ChannelFilterspanSpinnerValueChanged, true);
            app.ChannelFilterspanSpinner.Value = 10;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = channel_dialog(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end