classdef demapped_signal < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        GridLayout        matlab.ui.container.GridLayout
        TabGroup          matlab.ui.container.TabGroup
        TimesignalTab     matlab.ui.container.Tab
        GridLayout2       matlab.ui.container.GridLayout
        UIAxesTimeSignal  matlab.ui.control.UIAxes
        HistogramTab      matlab.ui.container.Tab
        GridLayout3       matlab.ui.container.GridLayout
        UIAxesHist        matlab.ui.control.UIAxes
    end

    
    methods (Access = public)
        
        function update_plots(app,demapping_type,M,sequence)
            switch demapping_type
                case 'hard'
                    set(app.UIAxesTimeSignal.Title, 'String', ...
                        'Output of hard-decision demapper');
                    set(app.UIAxesHist.Title, 'String', ...
                        'Histogram of hard-decision demapper output');
                    stem(app.UIAxesTimeSignal,0:length(sequence)-1,sequence);
                    app.UIAxesTimeSignal.XLim = [0 50];
                    app.UIAxesTimeSignal.YLim = [-0.5,1.5];
                    
                    cla(app.UIAxesHist)
                    legend(app.UIAxesHist, 'off');
                    h = histogram(app.UIAxesHist,sequence,-0.5:1:1.5);
                    h.Normalization = 'probability';
                case 'soft'
                    set(app.UIAxesTimeSignal.Title, 'String', ...
                        'Output of soft-demapper (Clipping at +100 and -100)');
                    set(app.UIAxesHist.Title, 'String', ...
                        'Histogram of soft-demapper output');
                    seq = sequence;
                    seq(sequence>100) = 100;
                    seq(sequence<-100) = -100;
                    stem(app.UIAxesTimeSignal,0:length(sequence)-1,seq);
                    app.UIAxesTimeSignal.XLim = [0 50];
                    
                    m = log2(M);
                    
                    max_val = max(abs(sequence));
                    legends = cell(1,m);
                    cla(app.UIAxesHist);
                    legend(app.UIAxesHist, 'off');
                    hold(app.UIAxesHist,'on');
                    for i = 1:m
                        legends{i} = sprintf('Bit-level %d', i);
                        histogram(app.UIAxesHist,sequence(i:m:end),linspace(-max_val,max_val,151),...
                            'Normalization','probability','FaceColor','auto',...
                            'FaceAlpha',0.5);
                        
                    end
                    legend(app.UIAxesHist,legends);
                    app.UIAxesHist.XGrid = 'on';
                    app.UIAxesHist.YGrid = 'off';
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, param, results)
            app.UIFigure.Name = 'Demapper output L';
            
            update_plots(app, param.demapping.type, param.modulation.M, results);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.TimesignalTab = uitab(app.TabGroup);
            app.TimesignalTab.Title = 'Time signal';
            app.GridLayout2 = uigridlayout(app.TimesignalTab);
            app.UIAxesTimeSignal = uiaxes(app.GridLayout2);
            title(app.UIAxesTimeSignal, 'Title')
            xlabel(app.UIAxesTimeSignal, 'i')
            ylabel(app.UIAxesTimeSignal, 'L_i')
            zlabel(app.UIAxesTimeSignal, 'Z')
            app.HistogramTab = uitab(app.TabGroup);
            app.HistogramTab.Title = 'Histogram';
            app.GridLayout3 = uigridlayout(app.HistogramTab);
            app.UIAxesHist = uiaxes(app.GridLayout3);
            title(app.UIAxesHist, 'Title')
            xlabel(app.UIAxesHist, 'X')
            ylabel(app.UIAxesHist, 'Y')
            zlabel(app.UIAxesHist, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = demapped_signal(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end