classdef equalizer_dialog < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        GridLayout2                 matlab.ui.container.GridLayout
        ParametersPanel             matlab.ui.container.Panel
        GridLayout3                 matlab.ui.container.GridLayout
        EqualizerTypeDropDownLabel  matlab.ui.control.Label
        EqualizerTypeDropDown       matlab.ui.control.DropDown
        K2K1isthenumberoftapsoftheequalizerSpinnerLabel  matlab.ui.control.Label
        KSpinner                    matlab.ui.control.Spinner
        GridLayout4                 matlab.ui.container.GridLayout
        SaveSettingsButton          matlab.ui.control.Button
        CancelButton                matlab.ui.control.Button
    end

    
    properties (Access = private)
        main_app % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, calling_app)
            app.main_app = calling_app;
            
            app.EqualizerTypeDropDown.Value = calling_app.param.equalizer.type;
            
            if strcmp(calling_app.param.equalizer.type,'zf_feedback')
                app.KSpinner.Enable = false;
                app.KSpinner.Editable = false;
                app.K2K1isthenumberoftapsoftheequalizerSpinnerLabel.Enable = false;
            else
                app.KSpinner.Value = calling_app.param.equalizer.K;
            end
        end

        % Button pushed function: SaveSettingsButton
        function SaveSettingsButtonPushed(app, event)
            equalizer.type = app.EqualizerTypeDropDown.Value;
            if strcmp(app.EqualizerTypeDropDown.Value,'zf_feedback')
                equalizer.K = 0;
            else
                equalizer.K = app.KSpinner.Value;
            end
            app.main_app.param.equalizer = equalizer; 
            app.main_app.update_shown_parameters();
            delete(app);
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            delete(app);
        end

        % Value changed function: EqualizerTypeDropDown
        function EqualizerTypeDropDownValueChanged(app, event)
            value = app.EqualizerTypeDropDown.Value;
            if strcmp(value,'zf_feedback')
                app.KSpinner.Enable = false;
                app.KSpinner.Editable = false;
                app.K2K1isthenumberoftapsoftheequalizerSpinnerLabel.Enable = false;
            else
                app.KSpinner.Enable = true;
                app.KSpinner.Editable = true;
                app.K2K1isthenumberoftapsoftheequalizerSpinnerLabel.Enable = true;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.ParametersPanel = uipanel(app.GridLayout2);
            app.ParametersPanel.Title = 'Parameters';
            app.GridLayout3 = uigridlayout(app.ParametersPanel);
            app.EqualizerTypeDropDownLabel = uilabel(app.GridLayout3);
            app.EqualizerTypeDropDownLabel.Text = 'Equalizer Type';
            app.EqualizerTypeDropDown = uidropdown(app.GridLayout3);
            app.EqualizerTypeDropDown.Items = {'LMMSE', 'Zero-Forcing '};
            app.EqualizerTypeDropDown.ItemsData = {'lmmse', 'zf'};
            app.EqualizerTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @EqualizerTypeDropDownValueChanged, true);
            app.EqualizerTypeDropDown.Value = 'lmmse';
            app.K2K1isthenumberoftapsoftheequalizerSpinnerLabel = uilabel(app.GridLayout3);
            app.K2K1isthenumberoftapsoftheequalizerSpinnerLabel.Text = 'K (2*K+1 is the number of taps of the equalizer)';
            app.KSpinner = uispinner(app.GridLayout3);
            app.KSpinner.Limits = [0 Inf];
            app.KSpinner.RoundFractionalValues = 'on';
            app.KSpinner.Value = 10;
            app.GridLayout4 = uigridlayout(app.GridLayout);
            app.SaveSettingsButton = uibutton(app.GridLayout4, 'push');
            app.SaveSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveSettingsButtonPushed, true);
            app.SaveSettingsButton.Text = 'Save Settings';
            app.CancelButton = uibutton(app.GridLayout4, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = equalizer_dialog(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end