classdef message_signal < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        GridLayout     matlab.ui.container.GridLayout
        TabGroup       matlab.ui.container.TabGroup
        TimeSignalTab  matlab.ui.container.Tab
        GridLayout2    matlab.ui.container.GridLayout
        UIAxes         matlab.ui.control.UIAxes
        HistogramTab   matlab.ui.container.Tab
        GridLayout3    matlab.ui.container.GridLayout
        UIAxes2        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)

    end
    
    properties (Access = public)
        ContentTab % Description
        GridLayoutContent
        Image
        Text
    end
    
    methods (Access = public)
        
        function update_plots(app,param,sequence)
            if isequal(app.TabGroup.SelectedTab,app.ContentTab)
                change_tab = true;
            else
                change_tab = false;
            end
            if isobject(app.ContentTab) && isvalid(app.ContentTab)
                delete(app.ContentTab);
            end
            switch param.source.type
                case 'image'
                    app.ContentTab = uitab(app.TabGroup);
                    app.ContentTab.Title = 'Image';
                    app.GridLayoutContent = uigridlayout(app.ContentTab);
                    app.GridLayoutContent.ColumnWidth = {'1x'};
                    app.GridLayoutContent.RowHeight = {'1x'};
                    app.Image = uiimage(app.GridLayoutContent);
                    app.Image.Layout.Row = 1;
                    app.Image.Layout.Column = 1;
                    if strcmp(param.source.image_type,'grayscale')
                        img = reshape(sequence,param.source.image.width,param.source.image.height)';
                        img = repmat(img,1,1,3);
                        app.Image.ImageSource = img;
                    else
                        img = permute(reshape(sequence,[3,param.source.image.width,param.source.image.height]),[3,2,1]);
                        app.Image.ImageSource = img;
                    end
                case 'audio'
                case 'text_utf8'
                    app.ContentTab = uitab(app.TabGroup);
                    app.ContentTab.Title = 'Text';
                    app.GridLayoutContent = uigridlayout(app.ContentTab);
                    app.GridLayoutContent.ColumnWidth = {'1x'};
                    app.GridLayoutContent.RowHeight = {'1x'};
                    app.Text =uitextarea(app.GridLayoutContent);
                    app.Text.Layout.Row = 1;
                    app.Text.Layout.Column = 1;
                    app.Text.Editable = false;

                    app.Text.Value = char(sequence);


            end
            h = histogram(app.UIAxes2,sequence,-0.5:1:param.source.cardinality-0.5);
            h.Normalization = 'probability';
            

            stem(app.UIAxes,0:length(sequence)-1,sequence);

            app.UIAxes.XLim = [0 50];
            app.UIAxes.XGrid = 'on';
            app.UIAxes.YGrid = 'on';
            if change_tab && (~strcmp(param.source.type,'dms'))
                app.TabGroup.SelectedTab = app.ContentTab;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, param, results, is_tx)
            
            
            if is_tx
                app.UIFigure.Name = 'Message Sequence';
            else
                app.UIFigure.Name = 'Received Message Sequence';
            end
            
            update_plots(app,param,results);
            
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.TimeSignalTab = uitab(app.TabGroup);
            app.TimeSignalTab.Title = 'Time Signal';
            app.GridLayout2 = uigridlayout(app.TimeSignalTab);
            app.UIAxes = uiaxes(app.GridLayout2);
            xlabel(app.UIAxes, 'i')
            ylabel(app.UIAxes, 'm_i')
            app.HistogramTab = uitab(app.TabGroup);
            app.HistogramTab.Title = 'Histogram';
            app.GridLayout3 = uigridlayout(app.HistogramTab);
            app.UIAxes2 = uiaxes(app.GridLayout3);
            xlabel(app.UIAxes2, 'm')
            ylabel(app.UIAxes2, 'P_M(m)')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = message_signal(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end