classdef mf_signal < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        TabGroup                     matlab.ui.container.TabGroup
        TimeSignalTab                matlab.ui.container.Tab
        GridLayout2                  matlab.ui.container.GridLayout
        UIAxesTimeReal               matlab.ui.control.UIAxes
        UIAxesTimeImag               matlab.ui.control.UIAxes
        ScatterTab                   matlab.ui.container.Tab
        GridLayout3                  matlab.ui.container.GridLayout
        UIAxesScatter                matlab.ui.control.UIAxes
        HistogramTab                 matlab.ui.container.Tab
        GridLayout9                  matlab.ui.container.GridLayout
        UIAxesHistReal               matlab.ui.control.UIAxes
        UIAxesHistImag               matlab.ui.control.UIAxes
        EyeDiagramTab                matlab.ui.container.Tab
        GridLayout4                  matlab.ui.container.GridLayout
        GridLayout5                  matlab.ui.container.GridLayout
        NumberofSymbolsSpinnerLabel  matlab.ui.control.Label
        NumberofSymbolsSpinner       matlab.ui.control.Spinner
        UIAxesEye                    matlab.ui.control.UIAxes
        UIAxesEyeImag                matlab.ui.control.UIAxes
        TrajectoriesTab              matlab.ui.container.Tab
        GridLayout6                  matlab.ui.container.GridLayout
        GridLayout7                  matlab.ui.container.GridLayout
        NumberofsymbolsSpinnerLabel  matlab.ui.control.Label
        NumberofsymbolsSpinnerTraj   matlab.ui.control.Spinner
        UIAxesTrajectories           matlab.ui.control.UIAxes
        SpectrumTab                  matlab.ui.container.Tab
        GridLayout8                  matlab.ui.container.GridLayout
        UIAxesSpectrum               matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        os
        %seq_tmp
        eye_time
        seq_traj
        seq_eye
    end
    
    properties (Access = public)
        ContTimer
        ContLastXYLims % Description
    end
    
    methods (Access = private)
        
        function ContTimerCallback(app,T,E)
            
            axRealLims = get(app.UIAxesTimeReal, {'xlim','ylim'});
            axImagLims = get(app.UIAxesTimeImag, {'xlim', 'ylim'});
            
            if(~isequal(axRealLims, app.ContLastXYLims))
                set(app.UIAxesTimeImag, {'xlim', 'ylim'}, axRealLims);
                app.ContLastXYLims = axRealLims;
            elseif(~isequal(axImagLims, app.ContLastXYLims))
                set(app.UIAxesTimeReal, {'xlim', 'ylim'}, axImagLims);
                app.ContLastXYLims = axImagLims;
            end
            
        end
        
        function draw_eye_diagram(app,num)
            if isreal(app.seq_eye)
                cla(app.UIAxesEyeImag);
            else
                plot(app.UIAxesEyeImag,app.eye_time,imag(app.seq_eye(:,1:num)),'b');
            end
            
            plot(app.UIAxesEye,app.eye_time,real(app.seq_eye(:,1:num)),'b');
        end
        
        function draw_trajectories(app,num)
            cla(app.UIAxesTrajectories);
            hold(app.UIAxesTrajectories,'on');

            plot(app.UIAxesTrajectories,real(app.seq_traj(1:app.os*num+1)),...
                imag(app.seq_traj(1:app.os*num+1)),'b');
            plot(app.UIAxesTrajectories,real(app.seq_traj(1:app.os:app.os*num+1)),...
                imag(app.seq_traj(1:app.os:app.os*num+1)),'b.');
        end
        

    end
    
    methods (Access = public)
        
        function update_plots(app,param,sequence_cont, sequence_disc,time,time_disc)
            
            app.os = param.general.os;
            symbol_rate = param.general.symbol_rate;       
            filterspan = param.pulse_shaping.filterspan + ...
                param.matched_filter.filterspan;
            
            if isfield(param.carrier_demodulation, 'filterspan')
                span_c = param.carrier_demodulation.filterspan;
            else
                span_c = 0;
            end
            if isfield(param.channel, 'filterspan')
                span_ch = param.channel.filterspan;
            else
                span_ch = 0;
            end
            
            T_s = 1/symbol_rate;
            cla(app.UIAxesTimeImag);
            cla(app.UIAxesTimeReal);
            
            % Plot the time plots in s, ms, ns,... in dependence of T_s
            if T_s >= 1e-1
                time_string = 't [s]';
                time_scale = 1;
            elseif T_s < 1e-1 && T_s >= 1e-4
                time_string = 't [ms]';
                time_scale = 1e3;
            elseif T_s < 1e-4 && T_s >= 1e-7
                time_string = ['t [',char(956),'s]'];
                time_scale = 1e6;
            elseif T_s < 1e-7 && T_s >= 1e-10
                time_string = 't [ns]';
                time_scale = 1e9;
            else
                time_string = 't [ps]';
                time_scale = 1e12;
            end
            app.UIAxesTimeReal.XLabel.String = time_string;
            app.UIAxesTimeImag.XLabel.String = time_string;
            app.UIAxesEye.XLabel.String = time_string;
            app.UIAxesEyeImag.XLabel.String = time_string;
            
            time = time_scale * time;
            time_disc = time_scale * time_disc;
            
            
            stop(app.ContTimer);
            if isreal(sequence_cont)
                cla(app.UIAxesTimeImag);
            else
                plot(app.UIAxesTimeImag,time,imag(sequence_cont));
                hold(app.UIAxesTimeImag,'on');
                stem(app.UIAxesTimeImag,time_disc,imag(sequence_disc));
            end

            plot(app.UIAxesTimeReal,time,real(sequence_cont));
            hold(app.UIAxesTimeReal,'on');
            stem(app.UIAxesTimeReal,time_disc,real(sequence_disc));
            
            app.ContLastXYLims = get(app.UIAxesTimeReal, {'xlim', 'ylim'});
            start(app.ContTimer);
            
            app.UIAxesTimeReal.YLim = [-1.1*max(abs(sequence_cont)),1.1*max(abs(sequence_cont))];
            app.UIAxesTimeReal.XLim = time_scale * ...
                [(filterspan/2+span_ch/2+span_c/2)/param.general.symbol_rate,...
                (filterspan/2+span_ch/2+span_c/2 + 20)/param.general.symbol_rate];
            app.UIAxesTimeReal.XGrid = 'on';
            app.UIAxesTimeReal.YGrid = 'on';
            app.UIAxesTimeImag.XGrid = 'on';
            app.UIAxesTimeImag.YGrid = 'on';


            %Scatter Plot
            scatter(app.UIAxesScatter,...
                real(sequence_disc),...
                imag(sequence_disc),'filled');
            x_max = max(abs(real(sequence_disc)));
            y_max = max(abs(imag(sequence_disc)));
            limit_max = 1.1* max(x_max,y_max);
            app.UIAxesScatter.XLim = [-limit_max, +limit_max];
            app.UIAxesScatter.YLim = [-limit_max, +limit_max];
            app.UIAxesScatter.PlotBoxAspectRatio = [1,1,1];
            
            if filterspan == 2
                app.seq_traj = sequence_cont(1:param.general.os*length(sequence_disc));
            else
                app.seq_traj = sequence_cont(...
                    (filterspan/2+span_c/2+span_ch/2)*param.general.os+1:...
                    (filterspan/2+span_c/2+span_ch/2)*param.general.os+param.general.os*length(sequence_disc));
            end
            app.seq_eye = reshape(app.seq_traj(param.general.os/2+1:end-param.general.os/2).',param.general.os,[]);
            app.seq_eye = app.seq_eye(:,2:end);
            % Eye Diagram
            app.eye_time = time_scale * linspace(-1/2/param.general.symbol_rate,1/2/param.general.symbol_rate*(1-2/param.general.os),param.general.os);
            app.UIAxesEye.XLim = time_scale * [-1/2/param.general.symbol_rate,1/2/param.general.symbol_rate];
            app.UIAxesEyeImag.XLim = time_scale * [-1/2/param.general.symbol_rate,1/2/param.general.symbol_rate];
            
            app.NumberofSymbolsSpinner.Limits = [1,size(app.seq_eye,2)];
            
            
            app.draw_eye_diagram(app.NumberofSymbolsSpinner.Value);
            
            app.NumberofsymbolsSpinnerTraj.Limits = [1,length(app.seq_traj)/app.os-1];
            
            app.draw_trajectories(app.NumberofsymbolsSpinnerTraj.Value);
            
            if symbol_rate <= 1e3
                app.UIAxesSpectrum.XLabel.String = 'f [Hz]';
                freq_scale = 1;
            elseif symbol_rate > 1e3 && symbol_rate <= 1e6
                app.UIAxesSpectrum.XLabel.String = 'f [kHz]';
                freq_scale = 1e-3;
            elseif symbol_rate > 1e6 && symbol_rate <= 1e9
                app.UIAxesSpectrum.XLabel.String = 'f [MHz]';
                freq_scale = 1e-6;
            elseif symbol_rate > 1e9 && symbol_rate <= 1e12
                app.UIAxesSpectrum.XLabel.String = 'f [GHz]';
                freq_scale = 1e-9;
            else
                app.UIAxesSpectrum.XLabel.String = 'f [THz]';
                freq_scale = 1e-12;
            end
            
            n = length(sequence_cont);
            delta_f = symbol_rate*app.os/n;
            freq = freq_scale * delta_f*((0:n-1)-floor(n/2));
            semilogy(app.UIAxesSpectrum,freq,abs(fftshift(fft(sequence_cont))));
            
            h1 = histogram(app.UIAxesHistReal,real(sequence_disc),512);
            h1.Normalization = 'probability';
            
            if isreal(sequence_disc)
                cla(app.UIAxesHistImag);
            else
                h2 = histogram(app.UIAxesHistImag,imag(sequence_disc),512);
                h2.Normalization = 'probability';
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, param, sequence_cont, sequence_disc, time, time_disc)
            
            %Name
            app.UIFigure.Name = 'Output of Matched Filter';
            
            %restore_time_real = findall(app.UIAxesTimeReal.Toolbar, ...
            %    'Tooltip', 'Restore View');
            
            
            app.ContTimer = timer('BusyMode','drop','ExecutionMode','fixedRate','Period',0.5,...
                 'TimerFcn',@(T,E) ContTimerCallback(app,T,E));
            
            update_plots(app,param,sequence_cont,sequence_disc, time, time_disc);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            stop(app.ContTimer)
            delete(app.ContTimer)
            delete(app)
            
        end

        % Value changed function: NumberofSymbolsSpinner
        function NumberofSymbolsSpinnerValueChanged(app, event)
            value = app.NumberofSymbolsSpinner.Value;
            app.draw_eye_diagram(value);
        end

        % Value changed function: NumberofsymbolsSpinnerTraj
        function NumberofsymbolsSpinnerTrajValueChanged(app, event)
            value = app.NumberofsymbolsSpinnerTraj.Value;
            app.draw_trajectories(value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.TimeSignalTab = uitab(app.TabGroup);
            app.TimeSignalTab.Title = 'Time Signal';
            app.GridLayout2 = uigridlayout(app.TimeSignalTab);
            app.UIAxesTimeReal = uiaxes(app.GridLayout2);
            title(app.UIAxesTimeReal, 'Real(y(t)) and Real(y_i)')
            xlabel(app.UIAxesTimeReal, 'X')
            zlabel(app.UIAxesTimeReal, 'Z')
            app.UIAxesTimeImag = uiaxes(app.GridLayout2);
            title(app.UIAxesTimeImag, 'Imag(y(t)) and Imag (y_i)')
            xlabel(app.UIAxesTimeImag, 'X')
            zlabel(app.UIAxesTimeImag, 'Z')
            app.ScatterTab = uitab(app.TabGroup);
            app.ScatterTab.Title = 'Scatter';
            app.GridLayout3 = uigridlayout(app.ScatterTab);
            app.UIAxesScatter = uiaxes(app.GridLayout3);
            xlabel(app.UIAxesScatter, 'Inphase')
            ylabel(app.UIAxesScatter, 'Quadrature')
            app.HistogramTab = uitab(app.TabGroup);
            app.HistogramTab.Title = 'Histogram';
            app.GridLayout9 = uigridlayout(app.HistogramTab);
            app.UIAxesHistReal = uiaxes(app.GridLayout9);
            xlabel(app.UIAxesHistReal, 'y_{real}')
            ylabel(app.UIAxesHistReal, 'Pr[Real(Y) = y_{real}]')
            app.UIAxesHistImag = uiaxes(app.GridLayout9);
            xlabel(app.UIAxesHistImag, 'y_{imag}')
            ylabel(app.UIAxesHistImag, 'Pr[Imag(Y) = y_{imag}]')
            app.EyeDiagramTab = uitab(app.TabGroup);
            app.EyeDiagramTab.Title = 'Eye Diagram';
            app.GridLayout4 = uigridlayout(app.EyeDiagramTab);
            app.GridLayout5 = uigridlayout(app.GridLayout4);
            app.NumberofSymbolsSpinnerLabel = uilabel(app.GridLayout5);
            app.NumberofSymbolsSpinnerLabel.Text = 'Number of Symbols';
            app.NumberofSymbolsSpinner = uispinner(app.GridLayout5);
            app.NumberofSymbolsSpinner.Step = 10;
            app.NumberofSymbolsSpinner.Limits = [1 Inf];
            app.NumberofSymbolsSpinner.RoundFractionalValues = 'on';
            app.NumberofSymbolsSpinner.ValueChangedFcn = createCallbackFcn(app, @NumberofSymbolsSpinnerValueChanged, true);
            app.NumberofSymbolsSpinner.Value = 100;
            app.UIAxesEye = uiaxes(app.GridLayout4);
            title(app.UIAxesEye, 'Eye Diagram of real(y(t))')
            app.UIAxesEyeImag = uiaxes(app.GridLayout4);
            title(app.UIAxesEyeImag, 'Eye Diagram of imag(y(t))')
            app.TrajectoriesTab = uitab(app.TabGroup);
            app.TrajectoriesTab.Title = 'Trajectories';
            app.GridLayout6 = uigridlayout(app.TrajectoriesTab);
            app.GridLayout7 = uigridlayout(app.GridLayout6);
            app.NumberofsymbolsSpinnerLabel = uilabel(app.GridLayout7);
            app.NumberofsymbolsSpinnerLabel.Text = 'Number of symbols';
            app.NumberofsymbolsSpinnerTraj = uispinner(app.GridLayout7);
            app.NumberofsymbolsSpinnerTraj.Step = 10;
            app.NumberofsymbolsSpinnerTraj.Limits = [1 Inf];
            app.NumberofsymbolsSpinnerTraj.RoundFractionalValues = 'on';
            app.NumberofsymbolsSpinnerTraj.ValueChangedFcn = createCallbackFcn(app, @NumberofsymbolsSpinnerTrajValueChanged, true);
            app.NumberofsymbolsSpinnerTraj.Value = 100;
            app.UIAxesTrajectories = uiaxes(app.GridLayout6);
            xlabel(app.UIAxesTrajectories, 'Inphase')
            ylabel(app.UIAxesTrajectories, 'Quadrature')
            app.SpectrumTab = uitab(app.TabGroup);
            app.SpectrumTab.Title = 'Spectrum';
            app.GridLayout8 = uigridlayout(app.SpectrumTab);
            app.UIAxesSpectrum = uiaxes(app.GridLayout8);
            xlabel(app.UIAxesSpectrum, 'f')
            ylabel(app.UIAxesSpectrum, 'abs(FFT(y(t)))')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mf_signal(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end