classdef modulation_dialog < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        GridLayout2                   matlab.ui.container.GridLayout
        SaveSettingsButton            matlab.ui.control.Button
        CancelButton                  matlab.ui.control.Button
        GridLayout3                   matlab.ui.container.GridLayout
        ModulationFormatButtonGroup   matlab.ui.container.ButtonGroup
        OOKButton                     matlab.ui.control.RadioButton
        MASKButton                    matlab.ui.control.RadioButton
        MQAMButton                    matlab.ui.control.RadioButton
        MPSKButton                    matlab.ui.control.RadioButton
        MDPSKButton                   matlab.ui.control.RadioButton
        GridLayout4                   matlab.ui.container.GridLayout
        ShowConstellationButton       matlab.ui.control.Button
        mlog2MSpinnerLabel            matlab.ui.control.Label
        mlog2MSpinner                 matlab.ui.control.Spinner
        MLabel                        matlab.ui.control.Label
        ChannelCodingPanel            matlab.ui.container.Panel
        GridLayout5                   matlab.ui.container.GridLayout
        ChannelCodeTypeDropDownLabel  matlab.ui.control.Label
        ChannelCodeTypeDropDown       matlab.ui.control.DropDown
        RepetitionCodeDimensionkEditFieldLabel  matlab.ui.control.Label
        RepetitionCodeDimensionkEditField  matlab.ui.control.NumericEditField
        RepetitionCodeFactorSpinnerLabel  matlab.ui.control.Label
        RepetitionCodeFactorSpinner   matlab.ui.control.Spinner
        DecoderDropDownLabel          matlab.ui.control.Label
        DecoderDropDown               matlab.ui.control.DropDown
    end

    
    properties (Access = private)
        main_app % Description
        M
    end
    
    methods (Access = private)
        
        
        function disable_M_editfield(app)
            app.mlog2MSpinner.Enable = false;
            app.mlog2MSpinner.Editable = false;
            app.mlog2MSpinnerLabel.Enable = false;
        end
        function enable_M_editfield(app)
            app.mlog2MSpinner.Enable = true;
            app.mlog2MSpinner.Editable = true;
            app.mlog2MSpinnerLabel.Enable = true;
        end
        function enable_repetition(app)
            app.RepetitionCodeFactorSpinner.Editable = true;
            app.RepetitionCodeFactorSpinner.Enable = true;
            app.RepetitionCodeFactorSpinnerLabel.Enable = true;
            app.RepetitionCodeDimensionkEditField.Editable = true;
            app.RepetitionCodeDimensionkEditField.Enable = true;
            app.RepetitionCodeDimensionkEditFieldLabel.Enable = true;
            app.DecoderDropDown.Editable = true;
            app.DecoderDropDown.Enable = true;
            app.DecoderDropDownLabel.Enable = true;
        end
        function disable_repetition(app)
            app.RepetitionCodeFactorSpinner.Editable = false;
            app.RepetitionCodeFactorSpinner.Enable = false;
            app.RepetitionCodeFactorSpinnerLabel.Enable = false;
            app.RepetitionCodeDimensionkEditField.Editable = false;
            app.RepetitionCodeDimensionkEditField.Enable = false;
            app.RepetitionCodeDimensionkEditFieldLabel.Enable = false;
            app.DecoderDropDown.Editable = false;
            app.DecoderDropDown.Enable = false;
            app.DecoderDropDownLabel.Enable = false;
        end

        function disable_code_type(app)
            app.ChannelCodeTypeDropDown.Editable = false;
            app.ChannelCodeTypeDropDown.Enable = false;
            app.ChannelCodeTypeDropDownLabel.Enable = false;
        end
        function enable_code_type(app)
            app.ChannelCodeTypeDropDown.Items = {'None', 'Repetition Code'};
            app.ChannelCodeTypeDropDown.ItemsData = {'none', 'rep'};
        end
        
        function update_M_label(app)
            app.MLabel.Text = sprintf('M = %d',app.M);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, calling_app)
            app.main_app = calling_app;
            app.M = calling_app.param.modulation.M;
            app.mlog2MSpinner.Value = log2(calling_app.param.modulation.M);
            switch calling_app.param.modulation.type
                case 'ook'
                    app.disable_M_editfield();
                    app.OOKButton.Value = true;
                case 'ask'
                    app.enable_M_editfield();
                    app.MASKButton.Value = true;
                    app.mlog2MSpinner.Step = 1;
                    app.mlog2MSpinner.Limits = [1,8];
                case 'qam'
                    app.enable_M_editfield();
                    app.MQAMButton.Value = true;
                    app.mlog2MSpinner.Step = 2;
                    app.mlog2MSpinner.Limits = [2,16];
                case 'psk'
                    app.enable_M_editfield();
                    app.MPSKButton.Value = true;
                    app.mlog2MSpinner.Step = 1;
                    app.mlog2MSpinner.Limits = [1,8];
                case 'dpsk'
                    app.enable_M_editfield();
                    app.MDPSKButton.Value = true;
                    app.mlog2MSpinner.Step = 1;
                    app.mlog2MSpinner.Limits = [1,8];
            end
            app.update_M_label();
            
            switch calling_app.param.channel_coding.type
                case 'none'
                    app.disable_repetition();
                    app.ChannelCodeTypeDropDown.Value = 'none';
                    app.disable_code_type();
                case 'rep'
                    app.enable_code_type();
                    app.ChannelCodeTypeDropDown.Value = 'rep';
                    app.RepetitionCodeDimensionkEditField.Value = ...
                        calling_app.param.channel_coding.k;
                    app.RepetitionCodeFactorSpinner.Value = ...
                        calling_app.param.channel_coding.rep_factor;
                    app.DecoderDropDown.Value = ...
                        calling_app.param.channel_coding.decoder;
            end
        end

        % Selection changed function: ModulationFormatButtonGroup
        function ModulationFormatButtonGroupSelectionChanged(app, event)
            selectedButton = app.ModulationFormatButtonGroup.SelectedObject;
            
            switch selectedButton.Text
                case 'OOK'
                    app.disable_M_editfield();
                    app.mlog2MSpinner.Value = 2;
                    app.mlog2MSpinner.Limits = [1,8];
                    app.mlog2MSpinner.Value = 1;
                case 'M-ASK'
                    app.enable_M_editfield();
                    app.mlog2MSpinner.Value = 2;
                    app.mlog2MSpinner.Limits = [1,8];
                    app.mlog2MSpinner.Value = 1;
                    app.mlog2MSpinner.Step = 1;
                case 'M-QAM'
                    app.enable_M_editfield();
                    app.mlog2MSpinner.Value = 2;
                    app.mlog2MSpinner.Limits = [2,16];
                    app.mlog2MSpinner.Step = 2;
                case 'M-PSK'
                    app.enable_M_editfield();
                    app.mlog2MSpinner.Value = 2;
                    app.mlog2MSpinner.Limits = [1,8];
                    app.mlog2MSpinner.Value = 1;
                    app.mlog2MSpinner.Step = 1;
                case 'M-DPSK'
                    app.enable_M_editfield();
                    app.mlog2MSpinner.Value = 2;
                    app.mlog2MSpinner.Limits = [1,8];
                    app.mlog2MSpinner.Value = 1;
                    app.mlog2MSpinner.Step = 1;
                    
                    % Set hard demapping if channel coding enabled
                    if ~strcmp(app.ChannelCodeTypeDropDown.Value,'none')
                        if strcmp(app.DecoderDropDown.Value, 'soft')
                            app.DecoderDropDown.Value = 'hard';
                            uialert(app.UIFigure,'Decoder Type has been changed to Hard Decision Decoding. Soft Decoding is not supported with M-DPSK',...
                                'Parameter changed!', 'Icon', 'info');
                        end
                    end
            end
            app.M = 2^app.mlog2MSpinner.Value;
            app.update_M_label();
        end

        % Button pushed function: SaveSettingsButton
        function SaveSettingsButtonPushed(app, event)
            switch app.ModulationFormatButtonGroup.SelectedObject.Text
                case 'OOK'
                    modulation.type = 'ook';
                    modulation.M = 2;
                case 'M-ASK'
                    modulation.type = 'ask';
                    modulation.M = app.M;
                case 'M-QAM'
                    modulation.type = 'qam';
                    modulation.M = app.M;
                case 'M-PSK'
                    modulation.type = 'psk';
                    modulation.M = app.M;
                case 'M-DPSK'
                    modulation.type = 'dpsk';
                    modulation.M = app.M;
            end
            
            modulation.is_initialized = false;
            
            app.main_app.param.modulation = modulation;
            
            demapping.is_initialized = false;
            
            app.main_app.param.demapping = demapping;
            
            
            switch app.ChannelCodeTypeDropDown.Value
                case 'none'
                    channel_coding.type = 'none';
                case 'rep'
                    channel_coding.type = 'rep';
                    channel_coding.k = app.RepetitionCodeDimensionkEditField.Value;
                    channel_coding.rep_factor = app.RepetitionCodeFactorSpinner.Value;
                    channel_coding.decoder = app.DecoderDropDown.Value;
            end
            
            channel_coding.is_initialized = false;
            
            app.main_app.param.channel_coding = channel_coding;
            
            if app.main_app.is_run
                app.main_app.UpToDateLabel.Visible = 'on';
            end
            app.main_app.update_shown_parameters();
            delete(app);
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            delete(app);
        end

        % Value changed function: ChannelCodeTypeDropDown
        function ChannelCodeTypeDropDownValueChanged(app, event)
            value = app.ChannelCodeTypeDropDown.Value;
            switch value
                case 'none'
                    app.disable_repetition();
                case 'rep'
                    app.enable_repetition();
            end
        end

        % Button pushed function: ShowConstellationButton
        function ShowConstellationButtonPushed(app, event)
            switch app.ModulationFormatButtonGroup.SelectedObject.Text
                case 'OOK'
                    modulation.show_constellation('ook',2);
                case 'M-ASK'
                    modulation.show_constellation('ask',app.M);
                case 'M-QAM'
                    modulation.show_constellation('qam',app.M);
                case 'M-PSK'
                    modulation.show_constellation('psk',app.M);
                case 'M-DPSK'
                    modulation.show_constellation('psk',app.M);
            end
        end

        % Value changed function: DecoderDropDown
        function DecoderDropDownValueChanged(app, event)
            value = app.DecoderDropDown.Value;
            if strcmp(value, 'soft') && strcmp(app.ModulationFormatButtonGroup.SelectedObject.Text,'M-DPSK')
                app.DecoderDropDown.Value = 'hard';
                uialert(app.UIFigure,...
                    'Soft Decoding is not supported for M-DPSK',...
                    'Invalid Parameter', 'Icon', 'warning');
            end
        end

        % Value changed function: mlog2MSpinner
        function mlog2MSpinnerValueChanged(app, event)
            value = app.mlog2MSpinner.Value;
            
            if strcmp(app.ModulationFormatButtonGroup.SelectedObject.Text,'M-QAM')
                if mod(value,2)
                    uialert(app.UIFigure,'m must be an even number between 2 and 16','Invalid Number');
                    app.mlog2MSpinner.Value = event.PreviousValue;
                end
            end
            
            app.M = 2^value;
            app.update_M_label();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Modulation Settings';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.SaveSettingsButton = uibutton(app.GridLayout2, 'push');
            app.SaveSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveSettingsButtonPushed, true);
            app.SaveSettingsButton.Text = 'Save Settings';
            app.CancelButton = uibutton(app.GridLayout2, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.ModulationFormatButtonGroup = uibuttongroup(app.GridLayout3);
            app.ModulationFormatButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ModulationFormatButtonGroupSelectionChanged, true);
            app.ModulationFormatButtonGroup.Title = 'Modulation Format';
            app.OOKButton = uiradiobutton(app.ModulationFormatButtonGroup);
            app.OOKButton.Text = 'OOK';
            app.OOKButton.Value = true;
            app.MASKButton = uiradiobutton(app.ModulationFormatButtonGroup);
            app.MASKButton.Text = 'M-ASK';
            app.MQAMButton = uiradiobutton(app.ModulationFormatButtonGroup);
            app.MQAMButton.Text = 'M-QAM';
            app.MPSKButton = uiradiobutton(app.ModulationFormatButtonGroup);
            app.MPSKButton.Text = 'M-PSK';
            app.MDPSKButton = uiradiobutton(app.ModulationFormatButtonGroup);
            app.MDPSKButton.Text = 'M-DPSK';
            app.GridLayout4 = uigridlayout(app.GridLayout3);
            app.ShowConstellationButton = uibutton(app.GridLayout4, 'push');
            app.ShowConstellationButton.ButtonPushedFcn = createCallbackFcn(app, @ShowConstellationButtonPushed, true);
            app.ShowConstellationButton.Text = 'Show Constellation';
            app.mlog2MSpinnerLabel = uilabel(app.GridLayout4);
            app.mlog2MSpinnerLabel.Text = 'm (log2 M)';
            app.mlog2MSpinner = uispinner(app.GridLayout4);
            app.mlog2MSpinner.Limits = [1 Inf];
            app.mlog2MSpinner.ValueChangedFcn = createCallbackFcn(app, @mlog2MSpinnerValueChanged, true);
            app.mlog2MSpinner.Value = 1;
            app.MLabel = uilabel(app.GridLayout4);
            app.ChannelCodingPanel = uipanel(app.GridLayout3);
            app.ChannelCodingPanel.Title = 'Channel Coding';
            app.GridLayout5 = uigridlayout(app.ChannelCodingPanel);
            app.ChannelCodeTypeDropDownLabel = uilabel(app.GridLayout5);
            app.ChannelCodeTypeDropDownLabel.Text = 'Channel Code Type';
            app.ChannelCodeTypeDropDown = uidropdown(app.GridLayout5);
            app.ChannelCodeTypeDropDown.Items = {'None'};
            app.ChannelCodeTypeDropDown.ItemsData = {'none'};
            app.ChannelCodeTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @ChannelCodeTypeDropDownValueChanged, true);
            app.ChannelCodeTypeDropDown.Value = 'none';
            app.RepetitionCodeDimensionkEditFieldLabel = uilabel(app.GridLayout5);
            app.RepetitionCodeDimensionkEditFieldLabel.Text = 'Repetition Code Dimension k:';
            app.RepetitionCodeDimensionkEditField = uieditfield(app.GridLayout5, 'numeric');
            app.RepetitionCodeDimensionkEditField.Limits = [1 Inf];
            app.RepetitionCodeDimensionkEditField.RoundFractionalValues = 'on';
            app.RepetitionCodeDimensionkEditField.ValueDisplayFormat = '%.0f';
            app.RepetitionCodeDimensionkEditField.Value = 1;
            app.RepetitionCodeFactorSpinnerLabel = uilabel(app.GridLayout5);
            app.RepetitionCodeFactorSpinnerLabel.Text = 'Repetition Code Factor:';
            app.RepetitionCodeFactorSpinner = uispinner(app.GridLayout5);
            app.RepetitionCodeFactorSpinner.Limits = [1 Inf];
            app.RepetitionCodeFactorSpinner.RoundFractionalValues = 'on';
            app.RepetitionCodeFactorSpinner.ValueDisplayFormat = '%.0f';
            app.RepetitionCodeFactorSpinner.Value = 1;
            app.DecoderDropDownLabel = uilabel(app.GridLayout5);
            app.DecoderDropDownLabel.Text = 'Decoder';
            app.DecoderDropDown = uidropdown(app.GridLayout5);
            app.DecoderDropDown.Items = {'Hard decision decoding', 'Soft decoding'};
            app.DecoderDropDown.ItemsData = {'hard', 'soft'};
            app.DecoderDropDown.ValueChangedFcn = createCallbackFcn(app, @DecoderDropDownValueChanged, true);
            app.DecoderDropDown.Value = 'hard';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = modulation_dialog(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end