classdef pulse_dialog < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        GridLayout2                matlab.ui.container.GridLayout
        SaveSettingsButton         matlab.ui.control.Button
        CancelButton               matlab.ui.control.Button
        GridLayout3                matlab.ui.container.GridLayout
        PulseTypeButtonGroup       matlab.ui.container.ButtonGroup
        RectangularButton          matlab.ui.control.RadioButton
        RaisedCosineButton         matlab.ui.control.RadioButton
        RootRaisedCosineButton     matlab.ui.control.RadioButton
        ParameterPanel             matlab.ui.container.Panel
        GridLayout4                matlab.ui.container.GridLayout
        FilterSpanSpinnerLabel     matlab.ui.control.Label
        FilterSpanSpinner          matlab.ui.control.Spinner
        FilterDesignDropDownLabel  matlab.ui.control.Label
        FilterDesignDropDown       matlab.ui.control.DropDown
        RolloffEditFieldLabel      matlab.ui.control.Label
        RolloffEditField           matlab.ui.control.Spinner
    end

    
    properties (Access = private)
        main_app % Description
    end
    
    methods (Access = private)
        
        function disable_rrc(app)
            app.FilterSpanSpinner.Editable = false;
            app.FilterSpanSpinner.Enable = false;
            app.FilterSpanSpinnerLabel.Enable = false;
            
            app.RolloffEditField.Editable = false;
            app.RolloffEditField.Enable = false;
            app.RolloffEditFieldLabel.Enable = false;
            
            app.FilterDesignDropDown.Enable = false;
            app.FilterDesignDropDownLabel.Enable = false;
        end
        
        function enable_rrc(app)
            app.FilterSpanSpinner.Editable = true;
            app.FilterSpanSpinner.Enable = true;
            app.FilterSpanSpinnerLabel.Enable = true;
            
            app.RolloffEditField.Editable = true;
            app.RolloffEditField.Enable = true;
            app.RolloffEditFieldLabel.Enable = true;
            
            app.FilterDesignDropDown.Enable = true;
            app.FilterDesignDropDownLabel.Enable = true;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, calling_app)
            app.main_app = calling_app;
            
            switch calling_app.param.pulse_shaping.type
                case 'rect'
                    app.RectangularButton.Value = true;
                    app.disable_rrc();
                case 'raisedcosine'
                    app.RaisedCosineButton.Value = true;
                    app.FilterSpanSpinner.Value = calling_app.param.pulse_shaping.filterspan;
                    app.RolloffEditField.Value = calling_app.param.pulse_shaping.rolloff;
                    app.FilterDesignDropDown.Value = calling_app.param.pulse_shaping.design;
                case 'rootraisedcosine'
                    app.RootRaisedCosineButton.Value = true;
                    app.FilterSpanSpinner.Value = calling_app.param.pulse_shaping.filterspan;
                    app.RolloffEditField.Value = calling_app.param.pulse_shaping.rolloff;
                    app.FilterDesignDropDown.Value = calling_app.param.pulse_shaping.design;
            end
        end

        % Selection changed function: PulseTypeButtonGroup
        function PulseTypeButtonGroupSelectionChanged(app, event)
            selectedButton = app.PulseTypeButtonGroup.SelectedObject;
            
            switch selectedButton.Text
                case 'Rectangular'
                    app.disable_rrc();
                case 'Raised Cosine'
                    app.enable_rrc();
                case 'Root Raised Cosine'
                    app.enable_rrc();
            end
        end

        % Value changed function: FilterSpanSpinner
        function FilterSpanSpinnerValueChanged(app, event)
            value = app.FilterSpanSpinner.Value;
            if mod(value,2)
                uialert(app.UIFigure,'Filter Span must be an even number larger than zero (2,4,6,...)','Invalid Number');
                app.FilterSpanSpinner.Value = event.PreviousValue;
            end
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            delete(app);
        end

        % Button pushed function: SaveSettingsButton
        function SaveSettingsButtonPushed(app, event)
            switch app.PulseTypeButtonGroup.SelectedObject.Text
                case 'Rectangular'
                    pulse_shaping.type = 'rect';
                case 'Raised Cosine'
                    pulse_shaping.type = 'raisedcosine';
                    pulse_shaping.rolloff = app.RolloffEditField.Value;
                    pulse_shaping.filterspan = app.FilterSpanSpinner.Value;
                    pulse_shaping.design = app.FilterDesignDropDown.Value;
                case 'Root Raised Cosine'
                    pulse_shaping.type = 'rootraisedcosine';
                    pulse_shaping.rolloff = app.RolloffEditField.Value;
                    pulse_shaping.filterspan = app.FilterSpanSpinner.Value;
                    pulse_shaping.design = app.FilterDesignDropDown.Value;
            end
            
            pulse_shaping.is_initialized = false;
            
            if app.main_app.is_run
                app.main_app.UpToDateLabel.Visible = 'on';
            end
            
            app.main_app.param.pulse_shaping = pulse_shaping;
            app.main_app.update_shown_parameters();
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.SaveSettingsButton = uibutton(app.GridLayout2, 'push');
            app.SaveSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveSettingsButtonPushed, true);
            app.SaveSettingsButton.Text = 'Save Settings';
            app.CancelButton = uibutton(app.GridLayout2, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.PulseTypeButtonGroup = uibuttongroup(app.GridLayout3);
            app.PulseTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PulseTypeButtonGroupSelectionChanged, true);
            app.PulseTypeButtonGroup.Title = 'Pulse Type';
            app.RectangularButton = uiradiobutton(app.PulseTypeButtonGroup);
            app.RectangularButton.Text = 'Rectangular';
            app.RectangularButton.Value = true;
            app.RaisedCosineButton = uiradiobutton(app.PulseTypeButtonGroup);
            app.RaisedCosineButton.Text = 'Raised Cosine';
            app.RootRaisedCosineButton = uiradiobutton(app.PulseTypeButtonGroup);
            app.RootRaisedCosineButton.Text = 'Root Raised Cosine';
            app.ParameterPanel = uipanel(app.GridLayout3);
            app.ParameterPanel.Title = 'Parameter';
            app.GridLayout4 = uigridlayout(app.ParameterPanel);
            app.FilterSpanSpinnerLabel = uilabel(app.GridLayout4);
            app.FilterSpanSpinnerLabel.Text = 'Filter Span';
            app.FilterSpanSpinner = uispinner(app.GridLayout4);
            app.FilterSpanSpinner.Step = 2;
            app.FilterSpanSpinner.Limits = [0 Inf];
            app.FilterSpanSpinner.RoundFractionalValues = 'on';
            app.FilterSpanSpinner.ValueChangedFcn = createCallbackFcn(app, @FilterSpanSpinnerValueChanged, true);
            app.FilterSpanSpinner.Value = 6;
            app.FilterDesignDropDownLabel = uilabel(app.GridLayout4);
            app.FilterDesignDropDownLabel.Text = 'Filter Design';
            app.FilterDesignDropDown = uidropdown(app.GridLayout4);
            app.FilterDesignDropDown.Items = {'Frequency Domain,', 'Time Domain'};
            app.FilterDesignDropDown.ItemsData = {'freq', 'time'};
            app.FilterDesignDropDown.Value = 'freq';
            app.RolloffEditFieldLabel = uilabel(app.GridLayout4);
            app.RolloffEditFieldLabel.Text = 'Rolloff';
            app.RolloffEditField = uispinner(app.GridLayout4);
            app.RolloffEditField.Step = 0.05;
            app.RolloffEditField.Limits = [0 1];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = pulse_dialog(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end