classdef r_signal < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        TabGroup                       matlab.ui.container.TabGroup
        TimeSignalTab                  matlab.ui.container.Tab
        GridLayout2                    matlab.ui.container.GridLayout
        UIAxesTimeReal                 matlab.ui.control.UIAxes
        UIAxesTimeReal2                matlab.ui.control.UIAxes
        UIAxesTimeImag                 matlab.ui.control.UIAxes
        UIAxesTimeImag2                matlab.ui.control.UIAxes
        SpectrumTab                    matlab.ui.container.Tab
        GridLayout3                    matlab.ui.container.GridLayout
        UIAxesSpectrum                 matlab.ui.control.UIAxes
        UIAxesSpectrumLowpass          matlab.ui.control.UIAxes
        ScatterTab                     matlab.ui.container.Tab
        GridLayout4                    matlab.ui.container.GridLayout
        UIAxesScatter                  matlab.ui.control.UIAxes
        EyeDiagramTab                  matlab.ui.container.Tab
        GridLayout5                    matlab.ui.container.GridLayout
        GridLayout6                    matlab.ui.container.GridLayout
        NumberofsymbolsSpinnerLabel    matlab.ui.control.Label
        NumberofsymbolsSpinner         matlab.ui.control.Spinner
        UIAxesEye                      matlab.ui.control.UIAxes
        UIAxesEyeImag                  matlab.ui.control.UIAxes
        TrajectoriesTab                matlab.ui.container.Tab
        GridLayout7                    matlab.ui.container.GridLayout
        GridLayout8                    matlab.ui.container.GridLayout
        NumberofsymbolsSpinner_2Label  matlab.ui.control.Label
        NumberofsymbolsSpinnerTraj     matlab.ui.control.Spinner
        UIAxesTrajectories             matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        os % Description
        time_difference
        seq_traj
        seq_eye
        eye_time
    end
    
    properties (Access = public)
        TimeTimer
        TimeLastXYLims % Description
        TimeLastXYLims2
        SpectrumTimer
        SpectrumLastXYLims

    end
    
    methods (Access = public)
        
        function update_plots(app, param, sequence, sequence_tmp, time, time_tmp)
            
            cla(app.UIAxesTimeReal);
            cla(app.UIAxesTimeReal2);
            cla(app.UIAxesTimeImag);
            cla(app.UIAxesTimeImag2);
            
            stop(app.TimeTimer);
            stop(app.SpectrumTimer);
            
            
            app.os = param.general.os;
            symbol_rate = param.general.symbol_rate;
            filterspan_pulse = param.pulse_shaping.filterspan;
            
            if isfield(param.carrier_demodulation, 'filterspan')
                span_c = param.carrier_demodulation.filterspan;
            else
                span_c = 0;
            end
            if isfield(param.channel, 'filterspan')
                span_ch = param.channel.filterspan;
            else
                span_ch = 0;
            end
            
            T_s = 1/symbol_rate;
            
            if T_s >= 1e-1
                time_string = 't [s]';
                time_scale = 1;
            elseif T_s < 1e-1 && T_s >= 1e-4
                time_string = 't [ms]';
                time_scale = 1e3;
            elseif T_s < 1e-4 && T_s >= 1e-7
                time_string = ['t [',char(956),'s]'];
                time_scale = 1e6;
            elseif T_s < 1e-7 && T_s >= 1e-10
                time_string = 't [ns]';
                time_scale = 1e9;
            else
                time_string = 't [ps]';
                time_scale = 1e12;
            end
            
            app.UIAxesTimeReal.XLabel.String = time_string;
            app.UIAxesTimeImag.XLabel.String = time_string;
            app.UIAxesTimeReal2.XLabel.String = time_string;
            app.UIAxesTimeImag2.XLabel.String = time_string;
            
            time = time_scale*time;
            
            plot(app.UIAxesTimeReal,time,real(sequence)); 
            app.UIAxesTimeReal.YLim = [-1.1*max(abs(real(sequence))),1.1*max(abs(real(sequence)))];
            
            if filterspan_pulse == 1
                app.UIAxesTimeReal.XLim = time_scale * ...
                    [(span_c/2 + span_ch/2)/symbol_rate,...
                    (20+span_c/2+span_ch/2)/symbol_rate];
            else
                app.UIAxesTimeReal.XLim = time_scale * ...
                    [(span_c/2 + span_ch/2 + filterspan_pulse/2)/symbol_rate,...
                    (span_c/2 + span_ch/2 + filterspan_pulse/2 + 20)/symbol_rate];
            end
            app.UIAxesTimeReal2.XLim = app.UIAxesTimeReal.XLim;
            app.UIAxesTimeImag.XLim = app.UIAxesTimeReal.XLim;
            app.UIAxesTimeImag2.XLim = app.UIAxesTimeReal.XLim;
            
            if ~isreal(sequence)
                plot(app.UIAxesTimeImag,time,imag(sequence)); 
                app.UIAxesTimeImag.YLim = [-1.1*max(abs(imag(sequence))),1.1*max(abs(imag(sequence)))];
            
            end
            
            if symbol_rate <= 1e3
                freq_string = 'f [Hz]';
                freq_scale = 1;
            elseif symbol_rate > 1e3 && symbol_rate <= 1e6
                freq_string = 'f [kHz]';
                freq_scale = 1e-3;
            elseif symbol_rate > 1e6 && symbol_rate <= 1e9
                freq_string = 'f [MHz]';
                freq_scale = 1e-6;
            elseif symbol_rate > 1e9 && symbol_rate <= 1e12
                freq_string = 'f [GHz]';
                freq_scale = 1e-9;
            else
                freq_string = 'f [THz]';
                freq_scale = 1e-12;
            end
            
            app.UIAxesSpectrum.XLabel.String = freq_string;
            app.UIAxesSpectrumLowpass.XLabel.String = freq_string;
            
            n = length(sequence);
            delta_f = symbol_rate*app.os/n;
            freq = freq_scale * delta_f*((0:n-1)-floor(n/2));
            spec = abs(fftshift(fft(sequence)));
            semilogy(app.UIAxesSpectrum,freq,spec);


            if filterspan_pulse == 1
                app.seq_traj = sequence((span_c/2+span_ch/2)*app.os+1:...
                    end-(span_c/2+span_ch/2)*app.os);
            else
                app.seq_traj = sequence((filterspan_pulse/2+span_c/2+span_ch/2)*app.os+1:...
                    end-(filterspan_pulse/2+span_c/2+span_ch/2)*app.os);
            end
            % make length of seq_traj divisible by os
            app.seq_traj = app.seq_traj(1:floor(length(app.seq_traj)/app.os)*app.os);
            scatter(app.UIAxesScatter,...
                real(app.seq_traj(1:app.os:end)),...
                imag(app.seq_traj(1:app.os:end)),'filled');
            x_max = max(abs(real(sequence)));
            y_max = max(abs(imag(sequence)));
            limit_max = 1.1* max(x_max,y_max);
            app.UIAxesScatter.XLim = [-limit_max, +limit_max];
            app.UIAxesScatter.YLim = [-limit_max, +limit_max];
            app.UIAxesScatter.PlotBoxAspectRatio = [1,1,1];


            % Eye Diagram
            if filterspan_pulse == 1
                app.seq_eye = reshape(app.seq_traj.',app.os,[]);
            else 
                app.seq_eye = reshape(app.seq_traj(app.os/2+1:end-app.os/2).',app.os,[]);
            end
            app.eye_time = time_scale * linspace(-1/2/symbol_rate,1/2/symbol_rate*(1-2/app.os),app.os);
            
            app.UIAxesEye.XLim = time_scale * [-1/2/symbol_rate,1/2/symbol_rate];
            app.UIAxesEyeImag.XLim = time_scale * [-1/2/symbol_rate,1/2/symbol_rate];
            
            app.NumberofsymbolsSpinner.Limits = [1,size(app.seq_eye,2)];
            app.NumberofsymbolsSpinnerTraj.Limits = [1,length(app.seq_traj)/app.os-1];
            
            app.draw_eye_diagram(app.NumberofsymbolsSpinner.Value);
            app.draw_trajectories(app.NumberofsymbolsSpinnerTraj.Value);


            if param.carrier_demodulation.lowpass

                
                plot(app.UIAxesTimeReal2,time_tmp,real(sequence_tmp));
                app.UIAxesTimeReal2.YLim = [-1.1*max(abs(real(sequence_tmp))),1.1*max(abs(real(sequence_tmp)))];
                
                if ~isreal(sequence_tmp)
                    plot(app.UIAxesTimeImag2,time_tmp,imag(sequence_tmp));
                    app.UIAxesTimeImag2.YLim = [-1.1*max(abs(imag(sequence_tmp))),1.1*max(abs(imag(sequence_tmp)))];
                end
                
                
                if filterspan_pulse == 1
                    app.UIAxesTimeReal2.XLim = time_scale * ...
                        [span_ch/2/symbol_rate,(20+span_ch/2)/symbol_rate];
                else
                    app.UIAxesTimeReal2.XLim = time_scale * ...
                        [(span_ch/2 + filterspan_pulse/2)/symbol_rate,...
                        (span_ch/2 + filterspan_pulse/2 + 20)/symbol_rate];
                end
                app.UIAxesTimeImag2.XLim = app.UIAxesTimeReal2.XLim;
                
                n = length(sequence_tmp);
                delta_f = symbol_rate*app.os/n;
                freq = freq_scale * delta_f*((0:n-1)-floor(n/2));
                spec_tmp = abs(fftshift(fft(sequence_tmp)));
                semilogy(app.UIAxesSpectrumLowpass,freq,spec_tmp);
                app.UIAxesSpectrum.YLim = [10^floor(log10(min(spec))),...
                    10^ceil(log10(max(spec)))];
                app.UIAxesSpectrumLowpass.YLim = [10^floor(log10(min(spec))),...
                    10^ceil(log10(max(spec)))];
                
                app.time_difference = app.UIAxesTimeReal2.XLim(1) - ...
                    app.UIAxesTimeReal.XLim(1);
            else
                app.time_difference = 0;
                app.UIAxesSpectrum.YLim = [10^floor(log10(min(spec))),...
                    10^ceil(log10(max(spec)))];
                cla(app.UIAxesSpectrumLowpass);
            end
            app.TimeLastXYLims = get(app.UIAxesTimeReal, {'xlim'});
            start(app.TimeTimer);
            start(app.SpectrumTimer);
        end
    end
    
    methods (Access = private)
        
        
        function draw_eye_diagram(app,num)
            if isreal(app.seq_eye)
                cla(app.UIAxesEyeImag);
            else
                plot(app.UIAxesEyeImag,app.eye_time,imag(app.seq_eye(:,1:num)),'b');
            end
            
            plot(app.UIAxesEye,app.eye_time,real(app.seq_eye(:,1:num)),'b');
        end
        
        
        function draw_trajectories(app,num)
            cla(app.UIAxesTrajectories);
            hold(app.UIAxesTrajectories,'on');
            plot(app.UIAxesTrajectories,real(app.seq_traj(1:app.os*num+1)),...
                imag(app.seq_traj(1:app.os*num+1)),'b');
            plot(app.UIAxesTrajectories,real(app.seq_traj(1:app.os:app.os*num+1)),...
                imag(app.seq_traj(1:app.os:app.os*num+1)),'b.');
        end
        
        function TimeTimerCallback(app,T,E)


            ax1 = get(app.UIAxesTimeReal, {'xlim'});
            ax2 = get(app.UIAxesTimeImag, {'xlim'});
            ax3 = get(app.UIAxesTimeReal2, {'xlim'});
            ax4 = get(app.UIAxesTimeImag2, {'xlim'});
            
            if(~isequal(ax1, app.TimeLastXYLims))
                ax_tmp = ax1;
                ax_tmp{1} = ax_tmp{1} + app.time_difference;
                
                set(app.UIAxesTimeReal2, {'xlim'}, ax_tmp);
                set(app.UIAxesTimeImag2, {'xlim'}, ax_tmp);
                set(app.UIAxesTimeImag, {'xlim'}, ax1);
 
                app.TimeLastXYLims = ax1;    
                app.TimeLastXYLims2 = ax_tmp;
                
            elseif(~isequal(ax2, app.TimeLastXYLims))
                ax_tmp = ax2;
                ax_tmp{1} = ax_tmp{1} + app.time_difference;
                
                set(app.UIAxesTimeReal2, {'xlim'}, ax_tmp);
                set(app.UIAxesTimeImag2, {'xlim'}, ax_tmp);
                set(app.UIAxesTimeReal, {'xlim'}, ax2);
 
                app.TimeLastXYLims = ax2;
                app.TimeLastXYLims2 = ax_tmp;
                
            elseif(~isequal(ax3, app.TimeLastXYLims2))
                ax_tmp = ax3;
                ax_tmp{1} = ax_tmp{1} - app.time_difference;
                
                set(app.UIAxesTimeReal, {'xlim'}, ax_tmp);
                set(app.UIAxesTimeImag, {'xlim'}, ax_tmp);
                set(app.UIAxesTimeImag2, {'xlim'}, ax3);
 
                app.TimeLastXYLims2 = ax3;
                app.TimeLastXYLims = ax_tmp;
                
            elseif(~isequal(ax4, app.TimeLastXYLims2))
                ax_tmp = ax4;
                ax_tmp{1} = ax_tmp{1} - app.time_difference;
                
                set(app.UIAxesTimeReal, {'xlim'}, ax_tmp);
                set(app.UIAxesTimeImag, {'xlim'}, ax_tmp);
                set(app.UIAxesTimeReal2, {'xlim'}, ax4);
 
                app.TimeLastXYLims2 = ax4;
                app.TimeLastXYLims = ax_tmp;
            end
            
        end
        function SpectrumTimerCallback(app,T,E)


            ax1 = get(app.UIAxesSpectrum, {'xlim','ylim'});
            ax2 = get(app.UIAxesSpectrumLowpass, {'xlim', 'ylim'});
            
            if(~isequal(ax1, app.SpectrumLastXYLims))
                set(app.UIAxesSpectrumLowpass, {'xlim', 'ylim'}, ax1);
                app.SpectrumLastXYLims = ax1;
            elseif(~isequal(ax2, app.SpectrumLastXYLims))
                set(app.UIAxesSpectrum, {'xlim', 'ylim'}, ax2);
                app.SpectrumLastXYLims = ax2;
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, param, sequence, sequence_lowpass, time, time_lowpass)


            app.TimeTimer = timer('BusyMode','drop','ExecutionMode','fixedRate','Period',0.5,...
                 'TimerFcn',@(T,E) TimeTimerCallback(app,T,E));
            
            app.SpectrumTimer = timer('BusyMode','drop','ExecutionMode','fixedRate','Period',0.5,...
                 'TimerFcn',@(T,E) SpectrumTimerCallback(app,T,E));
            
            update_plots(app, param, sequence, sequence_lowpass, time, time_lowpass);
            
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            try
                stop(app.TimeTimer);
                delete(app.TimeTimer);
                stop(app.SpectrumTimer);
                delete(app.SpectrumTimer);
                delete(app)
            catch
                delete(app);
            end
            
            
        end

        % Value changed function: NumberofsymbolsSpinnerTraj
        function NumberofsymbolsSpinnerTrajValueChanged(app, event)
            value = app.NumberofsymbolsSpinnerTraj.Value;
            app.draw_trajectories(value);
        end

        % Value changed function: NumberofsymbolsSpinner
        function NumberofsymbolsSpinnerValueChanged(app, event)
            value = app.NumberofsymbolsSpinner.Value;
            app.draw_eye_diagram(value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.TimeSignalTab = uitab(app.TabGroup);
            app.TimeSignalTab.Title = 'Time Signal';
            app.GridLayout2 = uigridlayout(app.TimeSignalTab);
            app.UIAxesTimeReal = uiaxes(app.GridLayout2);
            title(app.UIAxesTimeReal, 'Real(r(t))')
            xlabel(app.UIAxesTimeReal, 't')
            app.UIAxesTimeReal2 = uiaxes(app.GridLayout2);
            title(app.UIAxesTimeReal2, 'Real Component before lowpass filtering (empty without lowpass)')
            xlabel(app.UIAxesTimeReal2, 't')
            app.UIAxesTimeImag = uiaxes(app.GridLayout2);
            title(app.UIAxesTimeImag, 'Imag(r(t))')
            xlabel(app.UIAxesTimeImag, 't')
            app.UIAxesTimeImag2 = uiaxes(app.GridLayout2);
            title(app.UIAxesTimeImag2, 'Real Component before lowpass filtering (empty without lowpass)')
            xlabel(app.UIAxesTimeImag2, 't')
            app.SpectrumTab = uitab(app.TabGroup);
            app.SpectrumTab.Title = 'Spectrum';
            app.GridLayout3 = uigridlayout(app.SpectrumTab);
            app.UIAxesSpectrum = uiaxes(app.GridLayout3);
            title(app.UIAxesSpectrum, 'abs(fft(r(t))')
            zlabel(app.UIAxesSpectrum, 'Z')
            app.UIAxesSpectrumLowpass = uiaxes(app.GridLayout3);
            title(app.UIAxesSpectrumLowpass, 'Spectrum before lowpass filtering (empty without lowpass)')
            app.ScatterTab = uitab(app.TabGroup);
            app.ScatterTab.Title = 'Scatter';
            app.GridLayout4 = uigridlayout(app.ScatterTab);
            app.UIAxesScatter = uiaxes(app.GridLayout4);
            xlabel(app.UIAxesScatter, 'Inphase')
            ylabel(app.UIAxesScatter, 'Quadrature')
            app.EyeDiagramTab = uitab(app.TabGroup);
            app.EyeDiagramTab.Title = 'Eye Diagram';
            app.GridLayout5 = uigridlayout(app.EyeDiagramTab);
            app.GridLayout6 = uigridlayout(app.GridLayout5);
            app.NumberofsymbolsSpinnerLabel = uilabel(app.GridLayout6);
            app.NumberofsymbolsSpinnerLabel.Text = 'Number of symbols';
            app.NumberofsymbolsSpinner = uispinner(app.GridLayout6);
            app.NumberofsymbolsSpinner.Step = 10;
            app.NumberofsymbolsSpinner.Limits = [1 Inf];
            app.NumberofsymbolsSpinner.RoundFractionalValues = 'on';
            app.NumberofsymbolsSpinner.ValueChangedFcn = createCallbackFcn(app, @NumberofsymbolsSpinnerValueChanged, true);
            app.NumberofsymbolsSpinner.Value = 100;
            app.UIAxesEye = uiaxes(app.GridLayout5);
            title(app.UIAxesEye, 'Eye Diagram of real(s(t))')
            xlabel(app.UIAxesEye, 't [s]')
            app.UIAxesEyeImag = uiaxes(app.GridLayout5);
            title(app.UIAxesEyeImag, 'Eye Diagram of imag(s(t))')
            xlabel(app.UIAxesEyeImag, 't [s]')
            app.TrajectoriesTab = uitab(app.TabGroup);
            app.TrajectoriesTab.Title = 'Trajectories';
            app.GridLayout7 = uigridlayout(app.TrajectoriesTab);
            app.GridLayout8 = uigridlayout(app.GridLayout7);
            app.NumberofsymbolsSpinner_2Label = uilabel(app.GridLayout8);
            app.NumberofsymbolsSpinner_2Label.Text = 'Number of symbols';
            app.NumberofsymbolsSpinnerTraj = uispinner(app.GridLayout8);
            app.NumberofsymbolsSpinnerTraj.Step = 10;
            app.NumberofsymbolsSpinnerTraj.Limits = [1 Inf];
            app.NumberofsymbolsSpinnerTraj.RoundFractionalValues = 'on';
            app.NumberofsymbolsSpinnerTraj.ValueChangedFcn = createCallbackFcn(app, @NumberofsymbolsSpinnerTrajValueChanged, true);
            app.NumberofsymbolsSpinnerTraj.Value = 100;
            app.UIAxesTrajectories = uiaxes(app.GridLayout7);
            xlabel(app.UIAxesTrajectories, 'Inphase')
            ylabel(app.UIAxesTrajectories, 'Quadrature')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = r_signal(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end