classdef s_hf_signal < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        GridLayout      matlab.ui.container.GridLayout
        TabGroup        matlab.ui.container.TabGroup
        TimeSignalTab   matlab.ui.container.Tab
        GridLayout2     matlab.ui.container.GridLayout
        UIAxesTime      matlab.ui.control.UIAxes
        SpectrumTab     matlab.ui.container.Tab
        GridLayout3     matlab.ui.container.GridLayout
        UIAxesSpectrum  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        os % Description
        seq
    end
    
    methods (Access = private)
        
        function plot_time_signal(app)
            timeaxis = linspace(0,(length(app.seq)-1)/app.os,length(app.seq));
            
            plot(app.UIAxesTime,timeaxis,app.seq);
        end
    end
    
    methods (Access = public)
        
        function update_plots(app,param,sequence,time)
            app.os = param.general.os;
            symbol_rate = param.general.symbol_rate;
            filterspan = param.pulse_shaping.filterspan;
            
            T_s = 1/symbol_rate;
            
            if T_s >= 1e-1
                time_string = 't [s]';
                time_scale = 1;
            elseif T_s < 1e-1 && T_s >= 1e-4
                time_string = 't [ms]';
                time_scale = 1e3;
            elseif T_s < 1e-4 && T_s >= 1e-7
                time_string = ['t [',char(956),'s]'];
                time_scale = 1e6;
            elseif T_s < 1e-7 && T_s >= 1e-10
                time_string = 't [ns]';
                time_scale = 1e9;
            else
                time_string = 't [ps]';
                time_scale = 1e12;
            end
            
            app.UIAxesTime.XLabel.String = time_string;
            app.UIAxesTime.XLabel.String = time_string;
            
            time = time_scale*time;
            
            plot(app.UIAxesTime,time,real(sequence));
            
            app.UIAxesTime.YLim = [-1.1*max(abs(sequence)),1.1*max(abs(sequence))];
            
            if filterspan == 1
                app.UIAxesTime.XLim = time_scale * [0,20/symbol_rate];
            else
                app.UIAxesTime.XLim = time_scale * [filterspan/2/symbol_rate,...
                    (filterspan/2 + 20)/symbol_rate];
            end
            
            if symbol_rate <= 1e3
                app.UIAxesSpectrum.XLabel.String = 'f [Hz]';
                freq_scale = 1;
            elseif symbol_rate > 1e3 && symbol_rate <= 1e6
                app.UIAxesSpectrum.XLabel.String = 'f [kHz]';
                freq_scale = 1e-3;
            elseif symbol_rate > 1e6 && symbol_rate <= 1e9
                app.UIAxesSpectrum.XLabel.String = 'f [MHz]';
                freq_scale = 1e-6;
            elseif symbol_rate > 1e9 && symbol_rate <= 1e12
                app.UIAxesSpectrum.XLabel.String = 'f [GHz]';
                freq_scale = 1e-9;
            else
                app.UIAxesSpectrum.XLabel.String = 'f [THz]';
                freq_scale = 1e-12;
            end
            
            n = length(sequence);
            delta_f = symbol_rate*app.os/n;
            freq = freq_scale * delta_f*((0:n-1)-floor(n/2));
            semilogy(app.UIAxesSpectrum,freq,abs(fftshift(fft(sequence))));
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, param, sequence, time, is_tx)

            if is_tx
                app.UIFigure.Name = 's-tilde(t)';
                app.UIAxesTime.YLabel.String = 's-tilde(t)';
                app.UIAxesSpectrum.YLabel.String = 'abs(FFT(s-tilde(t)))';
            else
                app.UIFigure.Name = 'r-tilde(t)';
                app.UIAxesTime.YLabel.String = 'r-tilde(t)';
                app.UIAxesSpectrum.YLabel.String = 'abs(FFT(r-tilde(t)))';
            end
            
            update_plots(app,param,sequence,time);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.TimeSignalTab = uitab(app.TabGroup);
            app.TimeSignalTab.Title = 'Time Signal';
            app.GridLayout2 = uigridlayout(app.TimeSignalTab);
            app.UIAxesTime = uiaxes(app.GridLayout2);
            app.SpectrumTab = uitab(app.TabGroup);
            app.SpectrumTab.Title = 'Spectrum';
            app.GridLayout3 = uigridlayout(app.SpectrumTab);
            app.UIAxesSpectrum = uiaxes(app.GridLayout3);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = s_hf_signal(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end