classdef s_signal < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        TabGroup                       matlab.ui.container.TabGroup
        TimeSignalTab                  matlab.ui.container.Tab
        GridLayout4                    matlab.ui.container.GridLayout
        UIAxesTimeReal                 matlab.ui.control.UIAxes
        UIAxesTimeImag                 matlab.ui.control.UIAxes
        ScatterTab                     matlab.ui.container.Tab
        GridLayout3                    matlab.ui.container.GridLayout
        UIAxesScatter                  matlab.ui.control.UIAxes
        EyeDiagramTab                  matlab.ui.container.Tab
        GridLayout5                    matlab.ui.container.GridLayout
        GridLayout6                    matlab.ui.container.GridLayout
        NumberofsymbolsSpinnerLabel    matlab.ui.control.Label
        NumberofsymbolsSpinner         matlab.ui.control.Spinner
        UIAxesEye                      matlab.ui.control.UIAxes
        UIAxesEyeImag                  matlab.ui.control.UIAxes
        TrajectoriesTab                matlab.ui.container.Tab
        GridLayout7                    matlab.ui.container.GridLayout
        GridLayout8                    matlab.ui.container.GridLayout
        NumberofsymbolsSpinner_2Label  matlab.ui.control.Label
        NumberofsymbolsSpinnerTraj     matlab.ui.control.Spinner
        UIAxesTrajectories             matlab.ui.control.UIAxes
        SpectrumTab                    matlab.ui.container.Tab
        GridLayout9                    matlab.ui.container.GridLayout
        UIAxesSpectrum                 matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        os
        %filterspan
        %symbol_rate
        seq_traj
        seq_eye
        eye_time
    end
    
    properties (Access = public)
        MyTimer
        LastXYLims
    end
    
    methods (Access = private)
        
        function MyTimerCallback(app,T,E)
            
            axRealLims = get(app.UIAxesTimeReal, {'xlim','ylim'});
            axImagLims = get(app.UIAxesTimeImag, {'xlim', 'ylim'});
            
            if(~isequal(axRealLims, app.LastXYLims))
                set(app.UIAxesTimeImag, {'xlim', 'ylim'}, axRealLims);
                app.LastXYLims = axRealLims;
            elseif(~isequal(axImagLims, app.LastXYLims))
                set(app.UIAxesTimeReal, {'xlim', 'ylim'}, axImagLims);
                app.LastXYLims = axImagLims;
            end
            
        end
        
        function draw_eye_diagram(app,num)
            if isreal(app.seq_eye)
                cla(app.UIAxesEyeImag);
            else
                plot(app.UIAxesEyeImag,app.eye_time,imag(app.seq_eye(:,1:num)),'b');
            end
            
            plot(app.UIAxesEye,app.eye_time,real(app.seq_eye(:,1:num)),'b');
        end
        
        function draw_trajectories(app,num)
            cla(app.UIAxesTrajectories);
            hold(app.UIAxesTrajectories,'on');
            plot(app.UIAxesTrajectories,real(app.seq_traj(1:app.os*num+1)),...
                imag(app.seq_traj(1:app.os*num+1)),'b');
            plot(app.UIAxesTrajectories,real(app.seq_traj(1:app.os:app.os*num+1)),...
                imag(app.seq_traj(1:app.os:app.os*num+1)),'b.');
        end
    end
    
    methods (Access = public)
        
        function update_plots(app,param,sequence,time)
            
            app.os = param.general.os;
            symbol_rate = param.general.symbol_rate;
            filterspan = param.pulse_shaping.filterspan;
            
            T_s = 1/symbol_rate;
            
            if T_s >= 1e-1
                time_string = 't [s]';
                time_scale = 1;
            elseif T_s < 1e-1 && T_s >= 1e-4
                time_string = 't [ms]';
                time_scale = 1e3;
            elseif T_s < 1e-4 && T_s >= 1e-7
                time_string = ['t [',char(956),'s]'];
                time_scale = 1e6;
            elseif T_s < 1e-7 && T_s >= 1e-10
                time_string = 't [ns]';
                time_scale = 1e9;
            else
                time_string = 't [ps]';
                time_scale = 1e12;
            end
            
            app.UIAxesTimeReal.XLabel.String = time_string;
            app.UIAxesTimeImag.XLabel.String = time_string;
            app.UIAxesEye.XLabel.String = time_string;
            app.UIAxesEyeImag.XLabel.String = time_string;
            
            time = time_scale*time;
            
            stop(app.MyTimer);
            if isreal(sequence)
                cla(app.UIAxesTimeImag);
            else
                plot(app.UIAxesTimeImag,time,imag(sequence));
            end

            plot(app.UIAxesTimeReal,time,real(sequence));
            
            app.LastXYLims = get(app.UIAxesTimeReal, {'xlim', 'ylim'});
            start(app.MyTimer);
            
            app.UIAxesTimeReal.YLim = [-1.1*max(abs(sequence)),1.1*max(abs(sequence))];
            if filterspan == 1
                app.UIAxesTimeReal.XLim = time_scale * [0,19/symbol_rate];
            else
                app.UIAxesTimeReal.XLim = time_scale * [filterspan/2/symbol_rate,...
                    (filterspan/2 + 20)/symbol_rate];
            end
            app.UIAxesTimeReal.XGrid = 'on';
            app.UIAxesTimeReal.YGrid = 'on';
            app.UIAxesTimeImag.XGrid = 'on';
            app.UIAxesTimeImag.YGrid = 'on';
            
            if filterspan == 1
                app.seq_traj = sequence;
            else
                app.seq_traj = sequence(filterspan/2*app.os+1:end-filterspan/2*app.os);
            end
            % make length of seq_traj divisible by os
            app.seq_traj = app.seq_traj(1:floor(length(app.seq_traj)/app.os)*app.os);
            
            %Scatter Plot
            scatter(app.UIAxesScatter,...
                real(app.seq_traj(1:app.os:end)),...
                imag(app.seq_traj(1:app.os:end)),'filled');
            x_max = max(abs(real(sequence)));
            y_max = max(abs(imag(sequence)));
            limit_max = 1.1* max(x_max,y_max);
            app.UIAxesScatter.XLim = [-limit_max, +limit_max];
            app.UIAxesScatter.YLim = [-limit_max, +limit_max];
            app.UIAxesScatter.PlotBoxAspectRatio = [1,1,1];
            
            app.UIAxesTrajectories.XLim = [-limit_max, + limit_max];
            app.UIAxesTrajectories.YLim = [-limit_max, +limit_max];
            app.UIAxesTrajectories.PlotBoxAspectRatio = [1,1,1];
            
            % Eye Diagram
            if filterspan == 1
                app.seq_eye = reshape(app.seq_traj.',app.os,[]);
            else 
                app.seq_eye = reshape(app.seq_traj(app.os/2+1:end-app.os/2).',app.os,[]);
            end
            app.eye_time = time_scale * linspace(-1/2/symbol_rate,1/2/symbol_rate*(1-2/app.os),app.os);
            
            app.UIAxesEye.XLim = time_scale * [-1/2/symbol_rate,1/2/symbol_rate];
            app.UIAxesEyeImag.XLim = time_scale * [-1/2/symbol_rate,1/2/symbol_rate];
            
            app.NumberofsymbolsSpinner.Limits = [1,size(app.seq_eye,2)];
            app.NumberofsymbolsSpinnerTraj.Limits = [1,length(app.seq_traj)/app.os-1];
            
            app.draw_eye_diagram(app.NumberofsymbolsSpinner.Value);
            app.draw_trajectories(app.NumberofsymbolsSpinnerTraj.Value);
            
            if symbol_rate <= 1e3
                app.UIAxesSpectrum.XLabel.String = 'f [Hz]';
                freq_scale = 1;
            elseif symbol_rate > 1e3 && symbol_rate <= 1e6
                app.UIAxesSpectrum.XLabel.String = 'f [kHz]';
                freq_scale = 1e-3;
            elseif symbol_rate > 1e6 && symbol_rate <= 1e9
                app.UIAxesSpectrum.XLabel.String = 'f [MHz]';
                freq_scale = 1e-6;
            elseif symbol_rate > 1e9 && symbol_rate <= 1e12
                app.UIAxesSpectrum.XLabel.String = 'f [GHz]';
                freq_scale = 1e-9;
            else
                app.UIAxesSpectrum.XLabel.String = 'f [THz]';
                freq_scale = 1e-12;
            end
            
            n = length(sequence);
            delta_f = symbol_rate*app.os/n;
            freq = freq_scale * delta_f*((0:n-1)-floor(n/2));
            semilogy(app.UIAxesSpectrum,freq,abs(fftshift(fft(sequence))));
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, param, sequence, time, is_tx)

            if is_tx
                app.UIFigure.Name = 's(t)';
            else
                app.UIFigure.Name = 'r(t)';
                app.UIAxesTimeReal.YLabel.String = 'Real(r(t))';
                app.UIAxesTimeImag.YLabel.String = 'Imag(r(t))';
                app.UIAxesEye.Title.String = 'Eye Diagram of real(r(t))';
                app.UIAxesEyeImag.Title.String = 'Eye Diagram of imag(r(t))';
                app.UIAxesSpectrum.YLabel.String = 'abs(FFT(r(t)))';
            end
            
            app.MyTimer = timer('BusyMode','drop','ExecutionMode','fixedRate','Period',0.5,...
                 'TimerFcn',@(T,E) MyTimerCallback(app,T,E));

            update_plots(app,param,sequence,time);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            try
                stop(app.MyTimer);
                delete(app.MyTimer);

                delete(app)
            catch
                delete(app)
            end
            
        end

        % Value changed function: NumberofsymbolsSpinner
        function NumberofsymbolsSpinnerValueChanged(app, event)
            value = app.NumberofsymbolsSpinner.Value;
            app.draw_eye_diagram(value);
        end

        % Value changed function: NumberofsymbolsSpinnerTraj
        function NumberofsymbolsSpinnerTrajValueChanged(app, event)
            value = app.NumberofsymbolsSpinnerTraj.Value;
            app.draw_trajectories(value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.TimeSignalTab = uitab(app.TabGroup);
            app.TimeSignalTab.Title = 'Time Signal';
            app.GridLayout4 = uigridlayout(app.TimeSignalTab);
            app.UIAxesTimeReal = uiaxes(app.GridLayout4);
            xlabel(app.UIAxesTimeReal, 't [s]')
            ylabel(app.UIAxesTimeReal, 'Real(s(t))')
            zlabel(app.UIAxesTimeReal, 'Z')
            app.UIAxesTimeImag = uiaxes(app.GridLayout4);
            xlabel(app.UIAxesTimeImag, 't [s]')
            ylabel(app.UIAxesTimeImag, 'Imag(s(t))')
            zlabel(app.UIAxesTimeImag, 'Z')
            app.ScatterTab = uitab(app.TabGroup);
            app.ScatterTab.Title = 'Scatter';
            app.GridLayout3 = uigridlayout(app.ScatterTab);
            app.UIAxesScatter = uiaxes(app.GridLayout3);
            xlabel(app.UIAxesScatter, 'Inphase')
            ylabel(app.UIAxesScatter, 'Quadrature')
            app.EyeDiagramTab = uitab(app.TabGroup);
            app.EyeDiagramTab.Title = 'Eye Diagram';
            app.GridLayout5 = uigridlayout(app.EyeDiagramTab);
            app.GridLayout6 = uigridlayout(app.GridLayout5);
            app.NumberofsymbolsSpinnerLabel = uilabel(app.GridLayout6);
            app.NumberofsymbolsSpinnerLabel.Text = 'Number of symbols';
            app.NumberofsymbolsSpinner = uispinner(app.GridLayout6);
            app.NumberofsymbolsSpinner.Step = 10;
            app.NumberofsymbolsSpinner.Limits = [1 Inf];
            app.NumberofsymbolsSpinner.RoundFractionalValues = 'on';
            app.NumberofsymbolsSpinner.ValueChangedFcn = createCallbackFcn(app, @NumberofsymbolsSpinnerValueChanged, true);
            app.NumberofsymbolsSpinner.Value = 100;
            app.UIAxesEye = uiaxes(app.GridLayout5);
            title(app.UIAxesEye, 'Eye Diagram of real(s(t))')
            xlabel(app.UIAxesEye, 't [s]')
            app.UIAxesEyeImag = uiaxes(app.GridLayout5);
            title(app.UIAxesEyeImag, 'Eye Diagram of imag(s(t))')
            xlabel(app.UIAxesEyeImag, 't [s]')
            app.TrajectoriesTab = uitab(app.TabGroup);
            app.TrajectoriesTab.Title = 'Trajectories';
            app.GridLayout7 = uigridlayout(app.TrajectoriesTab);
            app.GridLayout8 = uigridlayout(app.GridLayout7);
            app.NumberofsymbolsSpinner_2Label = uilabel(app.GridLayout8);
            app.NumberofsymbolsSpinner_2Label.Text = 'Number of symbols';
            app.NumberofsymbolsSpinnerTraj = uispinner(app.GridLayout8);
            app.NumberofsymbolsSpinnerTraj.Step = 10;
            app.NumberofsymbolsSpinnerTraj.Limits = [1 Inf];
            app.NumberofsymbolsSpinnerTraj.RoundFractionalValues = 'on';
            app.NumberofsymbolsSpinnerTraj.ValueChangedFcn = createCallbackFcn(app, @NumberofsymbolsSpinnerTrajValueChanged, true);
            app.NumberofsymbolsSpinnerTraj.Value = 100;
            app.UIAxesTrajectories = uiaxes(app.GridLayout7);
            xlabel(app.UIAxesTrajectories, 'Inphase')
            ylabel(app.UIAxesTrajectories, 'Quadrature')
            app.SpectrumTab = uitab(app.TabGroup);
            app.SpectrumTab.Title = 'Spectrum';
            app.GridLayout9 = uigridlayout(app.SpectrumTab);
            app.UIAxesSpectrum = uiaxes(app.GridLayout9);
            ylabel(app.UIAxesSpectrum, 'abs(FFT(s(t)))')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = s_signal(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end