classdef show_parameter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        GridLayout2                matlab.ui.container.GridLayout
        SourceTextAreaLabel        matlab.ui.control.Label
        SourceTextArea             matlab.ui.control.TextArea
        SourceCodingTextAreaLabel  matlab.ui.control.Label
        SourceCodingTextArea       matlab.ui.control.TextArea
        ChannelCodingLabel         matlab.ui.control.Label
        ChannelCodingTextArea      matlab.ui.control.TextArea
        ModulationTextAreaLabel    matlab.ui.control.Label
        ModulationTextArea         matlab.ui.control.TextArea
        PulseShapingLabel          matlab.ui.control.Label
        PulseShapingTextArea       matlab.ui.control.TextArea
        CarrierModulationLabel     matlab.ui.control.Label
        CarrierModulationTextArea  matlab.ui.control.TextArea
        ChannelTextAreaLabel       matlab.ui.control.Label
        ChannelTextArea            matlab.ui.control.TextArea
        EqualizerTextAreaLabel     matlab.ui.control.Label
        EqualizerTextArea          matlab.ui.control.TextArea
    end

    
    properties (Access = public)
        SourceGrid
    end
    
    properties (Access = private)
        param
        paramListener
        callingApp
    end
    
    methods (Access = private)
        
        function show_source(app)
            app.SourceTextArea.Value = '';
            switch app.param.source.type 
                case 'dms'
                    app.SourceTextArea.Value{1} = 'Type: Discrete Memoryless Source';
                    app.SourceTextArea.Value{end+1} = sprintf('Cardinality: %d\n',app.param.source.cardinality);
                    app.SourceTextArea.Value{end+1} = sprintf('Sequence Length: %d\n',app.param.source.length);
                case 'image'
                    app.SourceTextArea.Value{1} = 'Type: Image';
                    app.SourceTextArea.Value{2} = sprintf('Image Type: %s',app.param.source.image_type);
                    app.SourceTextArea.Value{3} = sprintf('Filename: %s',app.param.source.filename);
                case 'audio'
                    app.SourceTextArea.Value{1} = 'Type: Audio';
                case 'text_utf8'
                    app.SourceTextArea.Value{1} = 'Type: Text';
                    app.SourceTextArea.Value{2} = sprintf('Filename: %s', app.param.source.text_utf8.filename);
            end
        end
        
        function show_source_coding(app)
            app.SourceCodingTextArea.Value = '';
            switch app.param.source_coding.type
                case 'none'
                    app.SourceCodingTextArea.Value{1} = 'No Source Coding';
                case 'huffman'
                    app.SourceCodingTextArea.Value{1} = 'Huffman Source Coding';
                    app.SourceCodingTextArea.Value{2} = sprintf('Huffman Table: %s', app.param.source_coding.huffman.tablefile);
                case 'huffman_img'
                    app.SourceCodingTextArea.Value{1} = 'Huffman Source Coding on differential images';
                    app.SourceCodingTextArea.Value{2} = sprintf('Huffman Table: %s', app.param.source_coding.huffman.tablefile);
            end
            
        end
        function show_channel_coding(app)
            app.ChannelCodingTextArea.Value = '';
            switch app.param.channel_coding.type
                case 'none'
                    app.ChannelCodingTextArea.Value{1} = 'No Channel Coding';
                case 'rep'
                    app.ChannelCodingTextArea.Value{1} = 'Repetition Coding';
                    app.ChannelCodingTextArea.Value{2} = sprintf('k: %d', app.param.channel_coding.k);
                    app.ChannelCodingTextArea.Value{3} = sprintf('Repetition factor: %d', app.param.channel_coding.rep_factor);
                    app.ChannelCodingTextArea.Value{4} = sprintf('Decoder Type: %s', app.param.channel_coding.decoder);
            end
            
        end
        function show_pulse_shaping(app)
            app.PulseShapingTextArea.Value = '';
            switch app.param.pulse_shaping.type
                case 'none'
                    app.PulseShapingTextArea.Value{1} = 'Discrete System => No Pulse Shaping';
                case 'raisedcosine'
                    app.PulseShapingTextArea.Value{1} = 'Pulse Type: Raised Cosine';
                    app.PulseShapingTextArea.Value{2} = sprintf('Rolloff: %.2f', app.param.pulse_shaping.rolloff);
                    app.PulseShapingTextArea.Value{3} = sprintf('Filter Span: %d', app.param.pulse_shaping.filterspan);
                case 'rootraisedcosine'
                    app.PulseShapingTextArea.Value{1} = 'Pulse Type: Root Raised Cosine';
                    app.PulseShapingTextArea.Value{2} = sprintf('Rolloff: %.2f', app.param.pulse_shaping.rolloff);
                    app.PulseShapingTextArea.Value{3} = sprintf('Filter Span: %d', app.param.pulse_shaping.filterspan);
                case 'rect'
                    app.PulseShapingTextArea.Value{1} = 'Pulse Type: Rectangular';
            end
        end
        
        function show_modulation(app)
            app.ModulationTextArea.Value = '';
            switch app.param.modulation.type
                case 'none'
                    app.ModulationTextArea.Value{1} = 'No Modulation';
                case 'ask'
                    app.ModulationTextArea.Value{1} = sprintf('Modulation format: %d-ASK',app.param.modulation.M);
                case 'qam'
                    app.ModulationTextArea.Value{1} = sprintf('Modulation format: %d-QAM',app.param.modulation.M);
                case 'psk'
                    app.ModulationTextArea.Value{1} = sprintf('Modulation format: %d-PSK',app.param.modulation.M);
                case 'ook'
                    app.ModulationTextArea.Value{1} = 'Modulation format: OOK';
                case 'dpsk'
                    app.ModulationTextArea.Value{1} = sprintf('Modulation format: %d-DPSK',app.param.modulation.M);
            end
        end
        
        function show_carrier_modulation(app)
            app.CarrierModulationTextArea.Value = '';
            switch app.param.carrier_modulation.type
                case 'none'
                    app.CarrierModulationTextArea.Value{1} = 'No Carrier Modulation';
                case 'am'
                    app.CarrierModulationTextArea.Value{1} = sprintf('Modulation Frequency: %.2f [1/T]', app.param.carrier_modulation.frequency);
                    app.CarrierModulationTextArea.Value{2} = sprintf('Demodulation Frequency: %.2f [1/T]', app.param.carrier_demodulation.frequency);
                    app.CarrierModulationTextArea.Value{3} = sprintf('Demodulator Phase Offset: %.3f', app.param.carrier_demodulation.phase);
                    
                    if app.param.carrier_demodulation.lowpass
                        app.CarrierModulationTextArea.Value{4} = sprintf('Lowpass Cutoff Frequency: %.2f [1/T]', app.param.carrier_demodulation.cutoff_frequency);
                        app.CarrierModulationTextArea.Value{5} = sprintf('Lowpass Filterspan: %d',app.param.carrier_demodulation.filterspan);
                    else
                        app.CarrierModulationTextArea.Value{4} = 'Without Lowpass filter';
                    end

            end
        end
        function show_equalizer(app)
            app.EqualizerTextArea.Value = '';
            switch app.param.equalizer.type 
                case 'none'
                    app.EqualizerTextArea.Value{1} = 'No Equalizer';
                case 'lmmse'
                    app.EqualizerTextArea.Value{1} = 'Linear Minimum Mean Square Error Equalizer';
                    K = app.param.equalizer.K;
                    app.EqualizerTextArea.Value{2} = sprintf('Number of Equalizer taps: %d (K=%d)', 2*K+1,K);
                case 'zf'
                    app.EqualizerTextArea.Value{1} = 'Zero-Forcing Equalizer';
                    K = app.param.equalizer.K;
                    app.EqualizerTextArea.Value{2} = sprintf('Number of Equalizer taps: %d (K=%d)', 2*K+1,K);
                    
            end
        end
        
        function show_channel(app)
            app.ChannelTextArea.Value = '';
            switch app.param.channel.type
                case 'none'
                    app.ChannelTextArea.Value{1} = 'Noise Type: No Noise';
                case 'awgn'
                    app.ChannelTextArea.Value{1} = 'Noise Type: AWGN';
                    app.ChannelTextArea.Value{2} = sprintf('SNR: %.2f [dB]',app.param.channel.snr);
            end
            
            if app.param.channel.is_bandwidth_limited
                app.ChannelTextArea.Value{end+1} = 'Channel Bandwitdh Limitation: on';
                app.ChannelTextArea.Value{end+1} = sprintf('Bandpass type: %s',app.param.channel_bandpass.type);
                app.ChannelTextArea.Value{end+1} = sprintf('Bandpass Rolloff: %.2f', app.param.channel_bandpass.rolloff);
                app.ChannelTextArea.Value{end+1} = sprintf('Bandpass Filter Span: %5',app.param.channel_bandpass.filterspan);
                app.ChannelTextArea.Value{end+1} = sprintf('Frequency Range: %.2f',app.param.channel_bandpass.frequency_range);
            else
                app.ChannelTextArea.Value{end+1} = 'Channel Bandwitdh Limitation: off';
            end
        end
    end
    
    methods (Access = public)
        function show_all_parameters(app,param)
            app.param = param;
            app.show_source();
            app.show_source_coding();
            app.show_channel_coding();
            app.show_pulse_shaping();
            app.show_channel();
            app.show_modulation();
            app.show_carrier_modulation();
            app.show_equalizer();
        end
%         function update_shown_parameters(app,param)
%             app.param = param;
%             app.show_source();
%             app.show_pulse_shaping();
%             app.show_modulation();
%             app.show_carrier_modulation();
%             app.show_channel();
%         end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, param, mainapp)
            app.callingApp = mainapp;

            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Parameter Overview';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.SourceTextAreaLabel = uilabel(app.GridLayout2);
            app.SourceTextAreaLabel.Text = 'Source';
            app.SourceTextArea = uitextarea(app.GridLayout2);
            app.SourceTextArea.Editable = 'off';
            app.SourceCodingTextAreaLabel = uilabel(app.GridLayout2);
            app.SourceCodingTextAreaLabel.Text = {'Source'; 'Coding'};
            app.SourceCodingTextArea = uitextarea(app.GridLayout2);
            app.SourceCodingTextArea.Editable = 'off';
            app.ChannelCodingLabel = uilabel(app.GridLayout2);
            app.ChannelCodingLabel.Text = {'Channel'; 'Coding'};
            app.ChannelCodingTextArea = uitextarea(app.GridLayout2);
            app.ChannelCodingTextArea.Editable = 'off';
            app.ModulationTextAreaLabel = uilabel(app.GridLayout2);
            app.ModulationTextAreaLabel.Text = 'Modulation';
            app.ModulationTextArea = uitextarea(app.GridLayout2);
            app.ModulationTextArea.Editable = 'off';
            app.PulseShapingLabel = uilabel(app.GridLayout2);
            app.PulseShapingLabel.Text = {'Pulse'; 'Shaping'};
            app.PulseShapingTextArea = uitextarea(app.GridLayout2);
            app.PulseShapingTextArea.Editable = 'off';
            app.CarrierModulationLabel = uilabel(app.GridLayout2);
            app.CarrierModulationLabel.Text = {'Carrier'; 'Modulation'};
            app.CarrierModulationTextArea = uitextarea(app.GridLayout2);
            app.CarrierModulationTextArea.Editable = 'off';
            app.ChannelTextAreaLabel = uilabel(app.GridLayout2);
            app.ChannelTextAreaLabel.Text = 'Channel';
            app.ChannelTextArea = uitextarea(app.GridLayout2);
            app.ChannelTextArea.Editable = 'off';
            app.EqualizerTextAreaLabel = uilabel(app.GridLayout2);
            app.EqualizerTextAreaLabel.Text = 'Equalizer';
            app.EqualizerTextArea = uitextarea(app.GridLayout2);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = show_parameter(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end