classdef source_dialog < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SourceSettingsUIFigure        matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        GridLayout2                   matlab.ui.container.GridLayout
        TabGroup                      matlab.ui.container.TabGroup
        DMSTab                        matlab.ui.container.Tab
        GridLayout3                   matlab.ui.container.GridLayout
        CardinalitySpinnerLabel       matlab.ui.control.Label
        CardinalitySpinner            matlab.ui.control.Spinner
        DistributionDropDownLabel     matlab.ui.control.Label
        DistributionDropDown          matlab.ui.control.DropDown
        nuLabel                       matlab.ui.control.Label
        nuEditField                   matlab.ui.control.Spinner
        SequenceLengthEditFieldLabel  matlab.ui.control.Label
        SequenceLengthEditField       matlab.ui.control.Spinner
        DistributionUIAxes            matlab.ui.control.UIAxes
        ImageTab                      matlab.ui.container.Tab
        GridLayout6                   matlab.ui.container.GridLayout
        ButtonGroup                   matlab.ui.container.ButtonGroup
        GrayscaleButton               matlab.ui.control.RadioButton
        RGBButton                     matlab.ui.control.RadioButton
        BrowseImageButton             matlab.ui.control.Button
        ImageLabel                    matlab.ui.control.Label
        Image                         matlab.ui.control.Image
        PreviewLabel                  matlab.ui.control.Label
        TextTab                       matlab.ui.container.Tab
        GridLayout7                   matlab.ui.container.GridLayout
        BrowseTextButton              matlab.ui.control.Button
        TextLabel                     matlab.ui.control.Label
        PreviewTextAreaLabel          matlab.ui.control.Label
        PreviewTextArea               matlab.ui.control.TextArea
        SourceTypeButtonGroup         matlab.ui.container.ButtonGroup
        DMSButton                     matlab.ui.control.RadioButton
        ImageButton                   matlab.ui.control.RadioButton
        TextButton                    matlab.ui.control.RadioButton
        GridLayout5                   matlab.ui.container.GridLayout
        SaveSettingsButton            matlab.ui.control.Button
        CancelButton                  matlab.ui.control.Button
    end

    
    properties (Access = private)
        main_app % Description
        image_file % Description
        text_file
        last_active_tab
    end
    
    methods (Access = private)
        
        function enable_dms_tab(app)
            app.CardinalitySpinner.Enable = true;
            app.CardinalitySpinner.Editable = true;
            app.CardinalitySpinnerLabel.Enable = true;
            app.SequenceLengthEditField.Enable = true;
            app.SequenceLengthEditField.Editable = true;
            app.SequenceLengthEditFieldLabel.Enable = true;
            app.DistributionDropDown.Enable = true;
            app.DistributionDropDownLabel.Enable = true;
            if strcmp(app.DistributionDropDown.Value,'boltzmann')
                app.nuEditField.Enable = true;
                app.nuLabel.Enable = true;
            end
            app.DistributionUIAxes.Visible = true;
        end
        
        function disable_dms_tab(app)
            app.CardinalitySpinner.Enable = false;
            app.CardinalitySpinner.Editable = false;
            app.CardinalitySpinnerLabel.Enable = false;
            app.SequenceLengthEditField.Enable = false;
            app.SequenceLengthEditField.Editable = false;
            app.SequenceLengthEditFieldLabel.Enable = false;
            app.DistributionDropDown.Enable = false;
            app.DistributionDropDownLabel.Enable = false;
            app.nuEditField.Enable = false;
            app.nuLabel.Enable = false;
            app.DistributionUIAxes.Visible = false;
        end
        
        function enable_image_tab(app)
            app.GrayscaleButton.Enable = true;
            app.RGBButton.Enable = true;
            app.BrowseImageButton.Enable = true;
            app.ButtonGroup.Enable = 'on';
            app.ImageLabel.Enable = true;
            app.PreviewLabel.Enable = true;
            app.Image.Enable = true;
        end
        
        function disable_image_tab(app)
            app.GrayscaleButton.Enable = false;
            app.RGBButton.Enable = false;
            app.BrowseImageButton.Enable = false;
            app.ButtonGroup.Enable = 'off';
            app.ImageLabel.Enable = false;
            app.PreviewLabel.Enable = false;
            app.Image.Enable = false;
        end

        
        function enable_text_tab(app)
            app.BrowseTextButton.Enable = true;
            app.PreviewTextArea.Enable = true;
            app.TextLabel.Enable = true;
            app.PreviewTextAreaLabel.Enable = true;
        end
        
        function disable_text_tab(app)
            app.BrowseTextButton.Enable = false;
            app.PreviewTextArea.Enable = false;
            app.TextLabel.Enable = false;
            app.PreviewTextAreaLabel.Enable = false;
        end
        
        function plot_distribution(app,cardinality,distribution,nu)
            switch distribution
                case 'uniform'
                    stem(app.DistributionUIAxes,0:cardinality-1,1/cardinality*ones(1,cardinality));
                case 'boltzmann'
                    x_tmp = linspace(0,1,cardinality);
                    mu = max(x_tmp)/2;
                    pX = exp(-nu * abs(x_tmp-mu).^2);
                    pX = pX/sum(pX);
                    stem(app.DistributionUIAxes,0:cardinality-1,pX);
            end
            app.DistributionUIAxes.XLim = [-1,cardinality];
            xlabel(app.DistributionUIAxes,'$m$','Interpreter','latex');
            ylabel(app.DistributionUIAxes, '$P_M$','Interpreter','latex');
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, calling_app)
            app.main_app = calling_app;
            
            switch calling_app.param.source.type
                case 'dms'
                    
                    app.DMSButton.Value = true;
                    app.TabGroup.SelectedTab = app.DMSTab;
                    app.SequenceLengthEditField.Value = calling_app.param.source.length;
                    app.CardinalitySpinner.Value = calling_app.param.source.cardinality;
                    app.DistributionDropDown.Value = calling_app.param.source.distribution;
                    if strcmp(calling_app.param.source.distribution,'boltzmann')
                        app.nuEditField.Value = calling_app.param.source.nu;
                    end
                    app.enable_dms_tab();
                    app.disable_image_tab();
                    app.disable_text_tab();
                    app.plot_distribution(app.CardinalitySpinner.Value,app.DistributionDropDown.Value,app.nuEditField.Value);
                    app.last_active_tab = 'dms';
                case 'image'
                    app.enable_image_tab();
                    app.disable_dms_tab();
                    app.disable_text_tab();
                    app.ImageButton.Value = true;
                    app.TabGroup.SelectedTab = app.ImageTab;
                    app.image_file = calling_app.param.source.filename;
                    [~,filename,ext] = fileparts(app.image_file);
                    app.ImageLabel.Text = [filename,ext];
                    switch calling_app.param.source.image_type
                        case 'grayscale'
                            app.GrayscaleButton.Value = true;
                            img = imread(app.image_file);
                            app.Image.ImageSource = repmat(img,1,1,3);
                        case 'rgb'
                            app.RGBButton.Value = true;
                            app.Image.ImageSource = app.image_file;
                    end
                    app.last_active_tab = 'image';
                case 'text_utf8'
                    app.enable_text_tab();
                    app.disable_dms_tab();
                    app.disable_image_tab();
                    app.TextButton.Value = true;
                    app.TabGroup.SelectedTab = app.TextTab;
                    app.last_active_tab = 'text_utf8';
                    app.text_file = calling_app.param.source.text_utf8.filename;
                    [~,filename,ext] = fileparts(app.text_file);
                    app.TextLabel.Text = [filename,ext];
                    app.PreviewTextArea.Value = fileread(app.text_file);
            end
            
        end

        % Selection changed function: SourceTypeButtonGroup
        function SourceTypeButtonGroupSelectionChanged(app, event)
            selectedButton = app.SourceTypeButtonGroup.SelectedObject;
            
            switch app.last_active_tab
                case 'dms'
                    app.disable_dms_tab();
                case 'image'
                    app.disable_image_tab();
                case 'text_utf8'
                    app.disable_text_tab();
            end
            
            switch selectedButton.Text
                case 'DMS'
                    app.enable_dms_tab();
                    app.TabGroup.SelectedTab = app.DMSTab;
                    app.last_active_tab = 'dms';
                    app.plot_distribution(app.CardinalitySpinner.Value,app.DistributionDropDown.Value,app.nuEditField.Value);
                case 'Image'
                    app.enable_image_tab();
                    app.TabGroup.SelectedTab = app.ImageTab;
                    app.last_active_tab = 'image';
                case 'Text'
                    app.enable_text_tab();
                    app.TabGroup.SelectedTab = app.TextTab;
                    app.last_active_tab = 'text_utf8';
            end
        end

        % Button pushed function: BrowseImageButton
        function BrowseImageButtonPushed(app, event)
            def_dir = fullfile(app.main_app.root,'files');
            if app.GrayscaleButton.Value
                filename = source.choose_image('grayscale',def_dir);
                if isempty(filename)
                    return
                end
                app.image_file = filename;
                img = imread(app.image_file);
                app.Image.ImageSource = repmat(img,1,1,3);
            else
                filename = source.choose_image('rgb',def_dir);
                if isempty(filename)
                    return
                end
                app.image_file = filename;
                app.Image.ImageSource = app.image_file;
            end
            drawnow;
            figure(app.SourceSettingsUIFigure);
            [~,filename,ext] = fileparts(app.image_file);
            app.ImageLabel.Text = [filename,ext];
        end

        % Button pushed function: SaveSettingsButton
        function SaveSettingsButtonPushed(app, event)
            switch app.SourceTypeButtonGroup.SelectedObject.Text
                case 'DMS'
                    source.type = 'dms';
                    source.cardinality = app.CardinalitySpinner.Value;
                    source.length = app.SequenceLengthEditField.Value;
                    source.distribution = app.DistributionDropDown.Value;
                    if strcmp(source.distribution,'boltzmann')
                        source.nu = app.nuEditField.Value;
                    end
                case 'Image'
                    if isempty(app.image_file)
                        uialert(app.SourceSettingsUIFigure,'No image selected!','Invalid Settings');
                        return;
                    end
                    source.type = 'image';
                    source.filename = app.image_file;
                    if app.GrayscaleButton.Value
                        source.image_type = 'grayscale';
                    else
                        source.image_type = 'rgb';
                    end
                case 'Text'
                    source.type = 'text_utf8';
                    if isempty(app.text_file)
                        uialert(app.SourceSettingsUIFigure, 'No text file selected!','Invalid Settings');
                        return;
                    end
                    source.text_utf8.filename = app.text_file;
            end
            source.is_initialized = false;
            app.main_app.param.source = source;
            app.main_app.update_shown_parameters();
            if app.main_app.is_run
                app.main_app.UpToDateLabel.Visible = 'on';
            end
            delete(app);
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            delete(app);
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            app.image_file = '';
            app.Image.ImageSource = '';
            app.ImageLabel.Text = 'no image selected';
        end

        % Button pushed function: BrowseTextButton
        function BrowseTextButtonPushed(app, event)
            f = figure();
            drawnow;
            f.Visible = 'off';
            [filename, pathname] = uigetfile({'*.txt','Text file (*.txt)'},'Select a Text File',fullfile(app.main_app.root,'files'));
            delete(f);
            drawnow;
            figure(app.SourceSettingsUIFigure);
            if isequal(filename,0)
                return
            end
            app.text_file = fullfile(pathname,filename);
            app.TextLabel.Text = filename;
            app.PreviewTextArea.Value = fileread(app.text_file);
        end

        % Value changing function: CardinalitySpinner
        function CardinalitySpinnerValueChanging(app, event)
            changingValue = event.Value;
            app.plot_distribution(changingValue,app.DistributionDropDown.Value,app.nuEditField.Value);
        end

        % Value changed function: DistributionDropDown
        function DistributionDropDownValueChanged(app, event)
            value = app.DistributionDropDown.Value;
            if strcmp(value,'uniform')
                app.nuEditField.Editable = false;
                app.nuEditField.Enable = false;
                app.nuLabel.Enable = false;
            else
                app.nuEditField.Editable = true;
                app.nuEditField.Enable = true;
                app.nuLabel.Enable = true;
            end
            app.plot_distribution(app.CardinalitySpinner.Value,value,app.nuEditField.Value);
        end

        % Value changed function: nuEditField
        function nuEditFieldValueChanged(app, event)
            value = app.nuEditField.Value;
            app.plot_distribution(app.CardinalitySpinner.Value,app.DistributionDropDown.Value,value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SourceSettingsUIFigure = uifigure('Visible', 'off');
            app.SourceSettingsUIFigure.Name = 'Source Settings';
            app.GridLayout = uigridlayout(app.SourceSettingsUIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.TabGroup = uitabgroup(app.GridLayout2);
            app.DMSTab = uitab(app.TabGroup);
            app.DMSTab.Title = 'DMS';
            app.GridLayout3 = uigridlayout(app.DMSTab);
            app.CardinalitySpinnerLabel = uilabel(app.GridLayout3);
            app.CardinalitySpinnerLabel.Enable = 'off';
            app.CardinalitySpinnerLabel.Text = 'Cardinality';
            app.CardinalitySpinner = uispinner(app.GridLayout3);
            app.CardinalitySpinner.ValueChangingFcn = createCallbackFcn(app, @CardinalitySpinnerValueChanging, true);
            app.CardinalitySpinner.Limits = [1 256];
            app.CardinalitySpinner.Editable = 'off';
            app.CardinalitySpinner.Enable = 'off';
            app.CardinalitySpinner.Value = 2;
            app.DistributionDropDownLabel = uilabel(app.GridLayout3);
            app.DistributionDropDownLabel.Enable = 'off';
            app.DistributionDropDownLabel.Text = 'Distribution';
            app.DistributionDropDown = uidropdown(app.GridLayout3);
            app.DistributionDropDown.Items = {'Uniform', 'Maxwell-Boltzmann'};
            app.DistributionDropDown.ItemsData = {'uniform', 'boltzmann', ''};
            app.DistributionDropDown.ValueChangedFcn = createCallbackFcn(app, @DistributionDropDownValueChanged, true);
            app.DistributionDropDown.Enable = 'off';
            app.DistributionDropDown.Value = 'uniform';
            app.nuLabel = uilabel(app.GridLayout3);
            app.nuLabel.Enable = 'off';
            app.nuLabel.Text = 'nu';
            app.nuEditField = uispinner(app.GridLayout3);
            app.nuEditField.Limits = [0 Inf];
            app.nuEditField.ValueChangedFcn = createCallbackFcn(app, @nuEditFieldValueChanged, true);
            app.nuEditField.Editable = 'off';
            app.nuEditField.Enable = 'off';
            app.nuEditField.Value = 10;
            app.SequenceLengthEditFieldLabel = uilabel(app.GridLayout3);
            app.SequenceLengthEditFieldLabel.Enable = 'off';
            app.SequenceLengthEditFieldLabel.Text = 'Sequence Length';
            app.SequenceLengthEditField = uispinner(app.GridLayout3);
            app.SequenceLengthEditField.Step = 100;
            app.SequenceLengthEditField.Limits = [1 Inf];
            app.SequenceLengthEditField.RoundFractionalValues = 'on';
            app.SequenceLengthEditField.Editable = 'off';
            app.SequenceLengthEditField.Enable = 'off';
            app.SequenceLengthEditField.Value = 1000;
            app.DistributionUIAxes = uiaxes(app.GridLayout3);
            title(app.DistributionUIAxes, 'Distribution')
            xlabel(app.DistributionUIAxes, 'X')
            ylabel(app.DistributionUIAxes, 'Y')
            zlabel(app.DistributionUIAxes, 'Z')
            app.ImageTab = uitab(app.TabGroup);
            app.ImageTab.Title = 'Image';
            app.GridLayout6 = uigridlayout(app.ImageTab);
            app.ButtonGroup = uibuttongroup(app.GridLayout6);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.ButtonGroup.Title = 'Button Group';
            app.GrayscaleButton = uiradiobutton(app.ButtonGroup);
            app.GrayscaleButton.Enable = 'off';
            app.GrayscaleButton.Text = 'Grayscale (.pgm)';
            app.GrayscaleButton.Value = true;
            app.RGBButton = uiradiobutton(app.ButtonGroup);
            app.RGBButton.Enable = 'off';
            app.RGBButton.Text = 'RGB (.jpg, .png)';
            app.BrowseImageButton = uibutton(app.GridLayout6, 'push');
            app.BrowseImageButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseImageButtonPushed, true);
            app.BrowseImageButton.Enable = 'off';
            app.BrowseImageButton.Text = 'Browse';
            app.ImageLabel = uilabel(app.GridLayout6);
            app.ImageLabel.Text = 'no image selected';
            app.Image = uiimage(app.GridLayout6);
            app.PreviewLabel = uilabel(app.GridLayout6);
            app.PreviewLabel.Text = 'Preview';
            app.TextTab = uitab(app.TabGroup);
            app.TextTab.Title = 'Text';
            app.GridLayout7 = uigridlayout(app.TextTab);
            app.BrowseTextButton = uibutton(app.GridLayout7, 'push');
            app.BrowseTextButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseTextButtonPushed, true);
            app.BrowseTextButton.Text = 'Browse';
            app.TextLabel = uilabel(app.GridLayout7);
            app.TextLabel.Text = 'no text file selected';
            app.PreviewTextAreaLabel = uilabel(app.GridLayout7);
            app.PreviewTextAreaLabel.Text = 'Preview';
            app.PreviewTextArea = uitextarea(app.GridLayout7);
            app.PreviewTextArea.Editable = 'off';
            app.SourceTypeButtonGroup = uibuttongroup(app.GridLayout2);
            app.SourceTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SourceTypeButtonGroupSelectionChanged, true);
            app.SourceTypeButtonGroup.Title = 'Source Type';
            app.DMSButton = uiradiobutton(app.SourceTypeButtonGroup);
            app.DMSButton.Text = 'DMS';
            app.DMSButton.Value = true;
            app.ImageButton = uiradiobutton(app.SourceTypeButtonGroup);
            app.ImageButton.Text = 'Image';
            app.TextButton = uiradiobutton(app.SourceTypeButtonGroup);
            app.TextButton.Text = 'Text';
            app.GridLayout5 = uigridlayout(app.GridLayout);
            app.SaveSettingsButton = uibutton(app.GridLayout5, 'push');
            app.SaveSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveSettingsButtonPushed, true);
            app.SaveSettingsButton.Text = 'Save Settings';
            app.CancelButton = uibutton(app.GridLayout5, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            % Show the figure after all components are created
            app.SourceSettingsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = source_dialog(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SourceSettingsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SourceSettingsUIFigure)
        end
    end
end