classdef source_encoding_dialog < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        GridLayout2                    matlab.ui.container.GridLayout
        CancelButton                   matlab.ui.control.Button
        SaveSettingsButton             matlab.ui.control.Button
        GridLayout3                    matlab.ui.container.GridLayout
        SourceCodingTypeDropDownLabel  matlab.ui.control.Label
        SourceCodingTypeDropDown       matlab.ui.control.DropDown
        BrowseButton                   matlab.ui.control.Button
        FileLabel                      matlab.ui.control.Label
    end

    
    properties (Access = private)
        main_app % Description
        huffman_file
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, calling_app)
            app.main_app = calling_app;
            
            app.SourceCodingTypeDropDown.Value = calling_app.param.source_coding.type;
            app.huffman_file = calling_app.param.source_coding.huffman.tablefile;
            [~,filename,ext] = fileparts(app.huffman_file);
            app.FileLabel.Text = [filename,ext];
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            delete(app);
        end

        % Button pushed function: SaveSettingsButton
        function SaveSettingsButtonPushed(app, event)
            source_coding.type = app.SourceCodingTypeDropDown.Value;
            source_coding.huffman.tablefile = app.huffman_file;
            source_coding.is_initialized = false;
            app.main_app.param.source_coding = source_coding;
            app.main_app.update_shown_parameters();
            if app.main_app.is_run
                app.main_app.UpToDateLabel.Visible = 'on';
            end
            delete(app);
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            f = figure();
            drawnow;
            f.Visible = 'off';
            [filename, pathname] = uigetfile({'*.mat','MAT-file (*.mat)'},'Select a  MAT-File',fullfile(app.main_app.root,'files'));
            delete(f);
            drawnow;
            figure(app.UIFigure);
            if isequal(filename,0)
                return
            end
            app.huffman_file = fullfile(pathname,filename);
            app.FileLabel.Text = filename;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Source Coding Settings';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.CancelButton = uibutton(app.GridLayout2, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.SaveSettingsButton = uibutton(app.GridLayout2, 'push');
            app.SaveSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveSettingsButtonPushed, true);
            app.SaveSettingsButton.Text = 'Save Settings';
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.SourceCodingTypeDropDownLabel = uilabel(app.GridLayout3);
            app.SourceCodingTypeDropDownLabel.Text = 'Source Coding Type';
            app.SourceCodingTypeDropDown = uidropdown(app.GridLayout3);
            app.SourceCodingTypeDropDown.Items = {'Huffman', 'Differential Image + Huffman'};
            app.SourceCodingTypeDropDown.ItemsData = {'huffman', 'huffman_img', ''};
            app.SourceCodingTypeDropDown.Value = 'huffman';
            app.BrowseButton = uibutton(app.GridLayout3, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.FileLabel = uilabel(app.GridLayout3);
            app.FileLabel.Text = 'no file selected';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = source_encoding_dialog(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end