classdef u_signal < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        GridLayout     matlab.ui.container.GridLayout
        TabGroup       matlab.ui.container.TabGroup
        TimeSignalTab  matlab.ui.container.Tab
        GridLayout2    matlab.ui.container.GridLayout
        UIAxes         matlab.ui.control.UIAxes
        HistogramTab   matlab.ui.container.Tab
        GridLayout3    matlab.ui.container.GridLayout
        UIAxes2        matlab.ui.control.UIAxes
    end

    
    methods (Access = public)
        
        function update_plots(app,sequence)
            h = histogram(app.UIAxes2,sequence,-0.5:1:1.5);
            h.Normalization = 'probability';
            
            stem(app.UIAxes,0:length(sequence)-1,sequence);
            app.UIAxes.XLim = [0 50];
            app.UIAxes.YLim = [-0.5,1.5];
            app.UIAxes.XGrid = 'on';
            app.UIAxes.YGrid = 'on';
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, param, results, is_tx)
            if is_tx
                app.UIFigure.Name = 'Tx u';
            else
                app.UIFigure.Name = 'Rx u';
            end
            
            update_plots(app,results);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.TimeSignalTab = uitab(app.TabGroup);
            app.TimeSignalTab.Title = 'Time Signal';
            app.GridLayout2 = uigridlayout(app.TimeSignalTab);
            app.UIAxes = uiaxes(app.GridLayout2);
            xlabel(app.UIAxes, 'i')
            ylabel(app.UIAxes, 'u_i')
            app.HistogramTab = uitab(app.TabGroup);
            app.HistogramTab.Title = 'Histogram';
            app.GridLayout3 = uigridlayout(app.HistogramTab);
            app.UIAxes2 = uiaxes(app.GridLayout3);
            xlabel(app.UIAxes2, 'u')
            ylabel(app.UIAxes2, 'P_U(u)')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = u_signal(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end