classdef x_signal < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        GridLayout      matlab.ui.container.GridLayout
        TabGroup        matlab.ui.container.TabGroup
        TimeSignalTab   matlab.ui.container.Tab
        GridLayout4     matlab.ui.container.GridLayout
        UIAxesTimeReal  matlab.ui.control.UIAxes
        UIAxesTimeImag  matlab.ui.control.UIAxes
        ScatterTab      matlab.ui.container.Tab
        GridLayout3     matlab.ui.container.GridLayout
        UIAxesScatter   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        seq % Description
    end
    
    properties (Access = public)
        MyTimer
        LastXYLims
    end
    
    methods (Access = private)


        function MyTimerCallback(app,T,E)
            
            axRealLims = get(app.UIAxesTimeReal, {'xlim','ylim'});
            axImagLims = get(app.UIAxesTimeImag, {'xlim', 'ylim'});
            
            if(~isequal(axRealLims, app.LastXYLims))
                set(app.UIAxesTimeImag, {'xlim', 'ylim'}, axRealLims);
                app.LastXYLims = axRealLims;
            elseif(~isequal(axImagLims, app.LastXYLims))
                set(app.UIAxesTimeReal, {'xlim', 'ylim'}, axImagLims);
                app.LastXYLims = axImagLims;
            end
            
        end
    end
    
    methods (Access = public)
        
        function update_plots(app, sequence)
            stop(app.MyTimer);
            if isreal(sequence)
                cla(app.UIAxesTimeImag);
            else
                stem(app.UIAxesTimeImag,0:length(sequence)-1,imag(sequence));
            end

            stem(app.UIAxesTimeReal,0:length(sequence)-1,real(sequence));
            
            app.LastXYLims = get(app.UIAxesTimeImag, {'xlim', 'ylim'});
            start(app.MyTimer);
            
            app.UIAxesTimeReal.XLim = [0,50];
            app.UIAxesTimeReal.XGrid = 'on';
            app.UIAxesTimeReal.YGrid = 'on';
            app.UIAxesTimeImag.XGrid = 'on';
            app.UIAxesTimeImag.YGrid = 'on';


            scatter(app.UIAxesScatter,real(sequence),imag(sequence),'filled');
            x_max = max(abs(real(sequence)));
            y_max = max(abs(imag(sequence)));
            limit_max = 1.1* max(x_max,y_max);
            app.UIAxesScatter.XLim = [-limit_max, +limit_max];
            app.UIAxesScatter.YLim = [-limit_max, +limit_max];
            app.UIAxesScatter.PlotBoxAspectRatio = [1,1,1];
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, param, results, is_tx)
            if is_tx
                app.UIFigure.Name = 'Transmitted symbols x';
            else
                app.UIFigure.Name = 'Received symbols x';
            end
            
            app.MyTimer = timer('BusyMode','drop','ExecutionMode','fixedRate','Period',0.5,...
                 'TimerFcn',@(T,E) MyTimerCallback(app,T,E));


            %linkaxes([app.UIAxesTimeReal,app.UIAxesTimeImag],'x');
            update_plots(app,results)
            %plot_time_signal(app);
            %plot_scatter(app);
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            stop(app.MyTimer);
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.TimeSignalTab = uitab(app.TabGroup);
            app.TimeSignalTab.Title = 'Time Signal';
            app.GridLayout4 = uigridlayout(app.TimeSignalTab);
            app.UIAxesTimeReal = uiaxes(app.GridLayout4);
            xlabel(app.UIAxesTimeReal, 'i')
            ylabel(app.UIAxesTimeReal, {'Real(x_i)'; ''})
            app.UIAxesTimeImag = uiaxes(app.GridLayout4);
            xlabel(app.UIAxesTimeImag, 'i')
            ylabel(app.UIAxesTimeImag, 'Imag(x_i)')
            app.ScatterTab = uitab(app.TabGroup);
            app.ScatterTab.Title = 'Scatter';
            app.GridLayout3 = uigridlayout(app.ScatterTab);
            app.UIAxesScatter = uiaxes(app.GridLayout3);
            xlabel(app.UIAxesScatter, 'Inphase')
            ylabel(app.UIAxesScatter, 'Quadrature')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = x_signal(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end