classdef y_discrete_signal < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        GridLayout      matlab.ui.container.GridLayout
        TabGroup        matlab.ui.container.TabGroup
        TimeSignalTab   matlab.ui.container.Tab
        GridLayout2     matlab.ui.container.GridLayout
        UIAxesTimeReal  matlab.ui.control.UIAxes
        UIAxesTimeImag  matlab.ui.control.UIAxes
        ScatterTab      matlab.ui.container.Tab
        GridLayout3     matlab.ui.container.GridLayout
        UIAxesScatter   matlab.ui.control.UIAxes
        HistogramTab    matlab.ui.container.Tab
        GridLayout4     matlab.ui.container.GridLayout
        UIAxesHistReal  matlab.ui.control.UIAxes
        UIAxesHistImag  matlab.ui.control.UIAxes
    end

    
    methods (Access = public)
        
        function update_plots(app, sequence)
            if isreal(sequence)
                cla(app.UIAxesTimeImag);
            else
                stem(app.UIAxesTimeImag,0:length(sequence)-1,imag(sequence));
            end
            
            stem(app.UIAxesTimeReal,0:length(sequence)-1,real(sequence));
            
            app.UIAxesTimeReal.XLim = [0,50];
            
            scatter(app.UIAxesScatter,real(sequence),imag(sequence),'filled');
            x_max = max(abs(real(sequence)));
            y_max = max(abs(imag(sequence)));
            limit_max = 1.1* max(x_max,y_max);
            app.UIAxesScatter.XLim = [-limit_max, +limit_max];
            app.UIAxesScatter.YLim = [-limit_max, +limit_max];
            app.UIAxesScatter.PlotBoxAspectRatio = [1,1,1];
            
            
            h1 = histogram(app.UIAxesHistReal,real(sequence),512);
            h1.Normalization = 'probability';
            
            if isreal(sequence)
                cla(app.UIAxesHistImag);
            else
                h2 = histogram(app.UIAxesHistImag,imag(sequence),512);
                h2.Normalization = 'probability';
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, param, results, title)
            app.UIFigure.Name = title;
            
            linkaxes([app.UIAxesTimeReal,app.UIAxesTimeImag],'x');
            update_plots(app, results);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.TimeSignalTab = uitab(app.TabGroup);
            app.TimeSignalTab.Title = 'Time Signal';
            app.GridLayout2 = uigridlayout(app.TimeSignalTab);
            app.UIAxesTimeReal = uiaxes(app.GridLayout2);
            xlabel(app.UIAxesTimeReal, 'i')
            ylabel(app.UIAxesTimeReal, 'Real(y_i)')
            zlabel(app.UIAxesTimeReal, 'Z')
            app.UIAxesTimeImag = uiaxes(app.GridLayout2);
            xlabel(app.UIAxesTimeImag, 'i')
            ylabel(app.UIAxesTimeImag, 'Imag(y_i)')
            zlabel(app.UIAxesTimeImag, 'Z')
            app.ScatterTab = uitab(app.TabGroup);
            app.ScatterTab.Title = 'Scatter';
            app.GridLayout3 = uigridlayout(app.ScatterTab);
            app.UIAxesScatter = uiaxes(app.GridLayout3);
            xlabel(app.UIAxesScatter, 'Real(y_i)')
            ylabel(app.UIAxesScatter, 'Imag(y_i)')
            app.HistogramTab = uitab(app.TabGroup);
            app.HistogramTab.Title = 'Histogram';
            app.GridLayout4 = uigridlayout(app.HistogramTab);
            app.UIAxesHistReal = uiaxes(app.GridLayout4);
            xlabel(app.UIAxesHistReal, {'Real(y)'; ''})
            ylabel(app.UIAxesHistReal, 'Pr[ Real(Y) = y]')
            app.UIAxesHistImag = uiaxes(app.GridLayout4);
            xlabel(app.UIAxesHistImag, 'Imag(y)')
            ylabel(app.UIAxesHistImag, {'Pr[ Imag(Y) = y ]'; ''})
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = y_discrete_signal(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end